classdef MAINPAGE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image3                          matlab.ui.control.Image
        HelpButton                      matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        UnitsPanel                      matlab.ui.container.Panel
        UNITSSwitch                     matlab.ui.control.Switch
        ToolboxPanel                    matlab.ui.container.Panel
        ReadfromFileButton              matlab.ui.control.Button
        SaveInputFileButton             matlab.ui.control.Button
        PARATHRUSTLabel                 matlab.ui.control.Label
        ComponentPropertiesPanel        matlab.ui.container.Panel
        NozzleExitAreaEditField         matlab.ui.control.NumericEditField
        NozzleExitAreaEditFieldLabel    matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        BurnerPressureRatioEditField    matlab.ui.control.NumericEditField
        BurnerPressureRatioEditFieldLabel  matlab.ui.control.Label
        TurbineMechanicalEfficiencyEditField  matlab.ui.control.NumericEditField
        TurbineMechanicalEfficiencyEditFieldLabel  matlab.ui.control.Label
        NozzleEfficiencyEditField       matlab.ui.control.NumericEditField
        NozzleEfficiencyEditFieldLabel  matlab.ui.control.Label
        TurbineIsentropicEfficiencyEditField  matlab.ui.control.NumericEditField
        TurbineIsentropicEfficiencyEditFieldLabel  matlab.ui.control.Label
        CompressorEfficiencyEditField   matlab.ui.control.NumericEditField
        CompressorEfficiencyEditFieldLabel  matlab.ui.control.Label
        GeneralPropertiesPanel          matlab.ui.container.Panel
        JKgLabel                        matlab.ui.control.Label
        FuelHeatingCapacityEditField    matlab.ui.control.NumericEditField
        FuelHeatingCapacityEditFieldLabel  matlab.ui.control.Label
        JKgKLabel_2                     matlab.ui.control.Label
        AirGasConstantREditField        matlab.ui.control.NumericEditField
        AirGasConstantREditFieldLabel   matlab.ui.control.Label
        JKgKLabel                       matlab.ui.control.Label
        HeatCapacityRatioEditField      matlab.ui.control.NumericEditField
        HeatCapacityRatioLabel          matlab.ui.control.Label
        SpecificHeatCpEditField         matlab.ui.control.NumericEditField
        SpecificHeatCpEditFieldLabel    matlab.ui.control.Label
        AnalysisTypePanel               matlab.ui.container.Panel
        PerformanceAnalysisButton       matlab.ui.control.Button
        ParametricAnalysisButton        matlab.ui.control.Button
        SingleCycleAnalysisButton       matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        para_test_1 % Description
    end
    
    properties (Access = private)
        performance_test % Description
    end
        
    properties (Access = private)
        Single_Cycle % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lalal)
            
            
                        value = app.UNITSSwitch.Value;
            
            switch value
                
                case 'Metric'

            app.CompressorEfficiencyEditField.Value = 0.9;
            app.NozzleEfficiencyEditField.Value = 0.87;
            app.TurbineIsentropicEfficiencyEditField.Value = 0.87;
            app.TurbineMechanicalEfficiencyEditField.Value = 0.85;
            app.BurnerPressureRatioEditField.Value = 0.96;
            
            app.SpecificHeatCpEditField.Value = 1000;
            app.NozzleExitAreaEditField.Value = 0.3;
            app.AirGasConstantREditField.Value = 287;          
            app.FuelHeatingCapacityEditField.Value = 42e6;
            app.HeatCapacityRatioEditField.Value = 1.333;
            
                case 'British'
            
            app.CompressorEfficiencyEditField.Value = 0.9;
            app.NozzleEfficiencyEditField.Value = 0.87;
            app.TurbineIsentropicEfficiencyEditField.Value = 0.87;
            app.TurbineMechanicalEfficiencyEditField.Value = 0.85;
            app.BurnerPressureRatioEditField.Value = 0.96;
            
            app.SpecificHeatCpEditField.Value = 0.2388;
            app.NozzleExitAreaEditField.Value = 465.001;
            app.AirGasConstantREditField.Value = 0.0685;
            app.FuelHeatingCapacityEditField.Value = 18056.7;
            app.HeatCapacityRatioEditField.Value = 1.333;
            
            end
            

        end

        % Button pushed function: ParametricAnalysisButton
        function ParametricAnalysisButtonPushed(app, event)
           
           value = app.UNITSSwitch.Value;
            
            switch value
                
                case 'Metric'

            %Component Properties
            cpr_eff= app.CompressorEfficiencyEditField.Value;
            Nozz_eff=app.NozzleEfficiencyEditField.Value;
            Tur_eff=app.TurbineIsentropicEfficiencyEditField.Value;
            Tur_mech_eff=app.TurbineMechanicalEfficiencyEditField.Value;
            Burn_eff=app.BurnerPressureRatioEditField.Value;
            
           %General Properties inputs
           Cp =app.SpecificHeatCpEditField.Value;
           nozz_area=app.NozzleExitAreaEditField.Value;
           Gas_Constant = app.AirGasConstantREditField.Value;
           Qhv=app.FuelHeatingCapacityEditField.Value;
           Gamma=app.HeatCapacityRatioEditField.Value;


           app.para_test_1 = para_test_1(app , cpr_eff , Nozz_eff, Tur_eff , Tur_mech_eff , Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma);
            
                case 'British'
            
            %Component Properties
            cpr_eff= app.CompressorEfficiencyEditField.Value;
            Nozz_eff=app.NozzleEfficiencyEditField.Value;
            Tur_eff=app.TurbineIsentropicEfficiencyEditField.Value;
            Tur_mech_eff=app.TurbineMechanicalEfficiencyEditField.Value;
            Burn_eff=app.BurnerPressureRatioEditField.Value;
            
           %General Properties inputs
           Cp =(1000/0.2388) * app.SpecificHeatCpEditField.Value;
           nozz_area=(0.3/465.001)* app.NozzleExitAreaEditField.Value;
           Gas_Constant = (1000/0.2388)*app.AirGasConstantREditField.Value;
           Qhv=(42e6/18056.7)*app.FuelHeatingCapacityEditField.Value;
           Gamma=app.HeatCapacityRatioEditField.Value;


           app.para_test_1 = para_test_1(app , cpr_eff , Nozz_eff, Tur_eff , Tur_mech_eff , Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma);
            
            end


        end

        % Button pushed function: PerformanceAnalysisButton
        function PerformanceAnalysisButtonPushed(app, event)
           
            value = app.UNITSSwitch.Value;
            
            switch value
                
                case 'Metric'

            %Component Properties
            cpr_eff= app.CompressorEfficiencyEditField.Value;
            Nozz_eff=app.NozzleEfficiencyEditField.Value;
            Tur_eff=app.TurbineIsentropicEfficiencyEditField.Value;
            Tur_mech_eff=app.TurbineMechanicalEfficiencyEditField.Value;
            Burn_eff=app.BurnerPressureRatioEditField.Value;
            
           %General Properties inputs
           Cp =app.SpecificHeatCpEditField.Value;
           nozz_area=app.NozzleExitAreaEditField.Value;
           Gas_Constant = app.AirGasConstantREditField.Value;
           Qhv=app.FuelHeatingCapacityEditField.Value;
           Gamma=app.HeatCapacityRatioEditField.Value;
           
          app.performance_test = performance_test(app , cpr_eff , Nozz_eff, Tur_eff , Tur_mech_eff , Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma );


                case 'British'
            
            %Component Properties
            cpr_eff= app.CompressorEfficiencyEditField.Value;
            Nozz_eff=app.NozzleEfficiencyEditField.Value;
            Tur_eff=app.TurbineIsentropicEfficiencyEditField.Value;
            Tur_mech_eff=app.TurbineMechanicalEfficiencyEditField.Value;
            Burn_eff=app.BurnerPressureRatioEditField.Value;
            
           %General Properties inputs
           Cp =(1000/0.2388) * app.SpecificHeatCpEditField.Value;
           nozz_area=(0.3/465.001)* app.NozzleExitAreaEditField.Value;
           Gas_Constant = (1000/0.2388)*app.AirGasConstantREditField.Value;
           Qhv=(42e6/18056.7)*app.FuelHeatingCapacityEditField.Value;
           Gamma=app.HeatCapacityRatioEditField.Value;


           app.performance_test = performance_test(app , cpr_eff , Nozz_eff, Tur_eff , Tur_mech_eff , Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma );
            
            end


        end

        % Button pushed function: SingleCycleAnalysisButton
        function SingleCycleAnalysisButtonPushed(app, event)
            
        value = app.UNITSSwitch.Value;
            
            switch value
                
                case 'Metric'

            %Component Properties
            cpr_eff= app.CompressorEfficiencyEditField.Value;
            Nozz_eff=app.NozzleEfficiencyEditField.Value;
            Tur_eff=app.TurbineIsentropicEfficiencyEditField.Value;
            Tur_mech_eff=app.TurbineMechanicalEfficiencyEditField.Value;
            Burn_eff=app.BurnerPressureRatioEditField.Value;
            
           %General Properties inputs
           Cp =app.SpecificHeatCpEditField.Value;
           nozz_area=app.NozzleExitAreaEditField.Value;
           Gas_Constant = app.AirGasConstantREditField.Value;
           Qhv=app.FuelHeatingCapacityEditField.Value;
           Gamma=app.HeatCapacityRatioEditField.Value;
           
           
          app.Single_Cycle = Single_Cycle(app , cpr_eff , Nozz_eff, Tur_eff , Tur_mech_eff , Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma );  


                case 'British'
            
            %Component Properties
            cpr_eff= app.CompressorEfficiencyEditField.Value;
            Nozz_eff=app.NozzleEfficiencyEditField.Value;
            Tur_eff=app.TurbineIsentropicEfficiencyEditField.Value;
            Tur_mech_eff=app.TurbineMechanicalEfficiencyEditField.Value;
            Burn_eff=app.BurnerPressureRatioEditField.Value;
            
           %General Properties inputs
           Cp =(1000/0.2388) * app.SpecificHeatCpEditField.Value;
           nozz_area=(0.3/465.001)* app.NozzleExitAreaEditField.Value;
           Gas_Constant = (1000/0.2388)*app.AirGasConstantREditField.Value;
           Qhv=(42e6/18056.7)*app.FuelHeatingCapacityEditField.Value;
           Gamma=app.HeatCapacityRatioEditField.Value;


           app.Single_Cycle = Single_Cycle(app , cpr_eff , Nozz_eff, Tur_eff , Tur_mech_eff , Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma );  
            
            end


        end

        % Button pushed function: SaveInputFileButton
        function SaveInputFileButtonPushed(app, event)
            [file,path] = uiputfile('*.txt','Turbojet File');
            
            full_directory = fullfile(path,file);
            
            f = fopen(full_directory,'w');
            
            %Component Properties
            cpr_eff= app.CompressorEfficiencyEditField.Value;
            Nozz_eff=app.NozzleEfficiencyEditField.Value;
            Tur_eff=app.TurbineIsentropicEfficiencyEditField.Value;
            Tur_mech_eff=app.TurbineMechanicalEfficiencyEditField.Value;
            Burn_eff=app.BurnerPressureRatioEditField.Value;
            
           %General Properties inputs
           Cp =app.SpecificHeatCpEditField.Value;
           nozz_area=app.NozzleExitAreaEditField.Value;
           Gas_Constant = app.AirGasConstantREditField.Value;
           Qhv=app.FuelHeatingCapacityEditField.Value;
           Gamma=app.HeatCapacityRatioEditField.Value;
            
            fprintf(f,'%d\n',cpr_eff , Nozz_eff, Tur_eff , Tur_mech_eff , Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma);
        end

        % Button pushed function: ReadfromFileButton
        function ReadfromFileButtonPushed(app, event)
           [file,path] = uigetfile('*.txt','Turbojet File');
           
           full_directory = fullfile(path,file);
            
            Data = readmatrix(full_directory);
            
           app.CompressorEfficiencyEditField.Value = Data(1,1);
           app.NozzleEfficiencyEditField.Value= Data(2,1);
           app.TurbineIsentropicEfficiencyEditField.Value= Data(3,1);
           app.TurbineMechanicalEfficiencyEditField.Value= Data(4,1);
           app.BurnerPressureRatioEditField.Value= Data(5,1);
            
           %General Properties inputs
           app.SpecificHeatCpEditField.Value= Data(6,1);
           app.NozzleExitAreaEditField.Value= Data(7,1);
           app.AirGasConstantREditField.Value= Data(8,1);
           app.FuelHeatingCapacityEditField.Value= Data(9,1);
           app.HeatCapacityRatioEditField.Value= Data(10,1);
        end

        % Value changed function: UNITSSwitch
        function UNITSSwitchValueChanged(app, event)
            value = app.UNITSSwitch.Value;
            
            switch value
                
                case 'Metric'

            app.m2Label.Text = 'm^2';
            app.JKgKLabel.Text = 'J/Kg.K';
            app.JKgKLabel_2.Text = 'J/Kg.K';
            app.JKgLabel.Text = 'J/Kg';
            
            app.CompressorEfficiencyEditField.Value = 0.9;
            app.NozzleEfficiencyEditField.Value = 0.87;
            app.TurbineIsentropicEfficiencyEditField.Value = 0.87;
            app.TurbineMechanicalEfficiencyEditField.Value = 0.85;
            app.BurnerPressureRatioEditField.Value = 0.96;
            
            app.SpecificHeatCpEditField.Value = 1000;
            app.NozzleExitAreaEditField.Value = 0.3;
            app.AirGasConstantREditField.Value = 287;
            app.FuelHeatingCapacityEditField.Value = 42e6;
            app.HeatCapacityRatioEditField.Value = 1.333;
            
                case 'British'
            
            app.m2Label.Text = 'in^2';
            app.JKgKLabel.Text = 'BTU/lb.R';
            app.JKgKLabel_2.Text = 'BTU/lb.R';
            app.JKgLabel.Text = 'BTU/lb';
            
            app.CompressorEfficiencyEditField.Value = 0.9;
            app.NozzleEfficiencyEditField.Value = 0.87;
            app.TurbineIsentropicEfficiencyEditField.Value = 0.87;
            app.TurbineMechanicalEfficiencyEditField.Value = 0.85;
            app.BurnerPressureRatioEditField.Value = 0.96;
            
            app.SpecificHeatCpEditField.Value = 0.2388;
            app.NozzleExitAreaEditField.Value = 465.001;
            app.AirGasConstantREditField.Value = 0.0685;
            app.FuelHeatingCapacityEditField.Value = 18056.7;
            app.HeatCapacityRatioEditField.Value = 1.333;
            
            end


        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
web('https://drive.google.com/file/d/1FghEc2Yhan_HNE59XaQSqdFGuJ0Mow5E/view')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'milad-fakurian-UiiHVEyxtyA-unsplash.jpg';
            app.AnalysisTypePanel = uipanel(app.UIFigure);
            app.AnalysisTypePanel.Title = 'Analysis Type';
            app.SingleCycleAnalysisButton = uibutton(app.AnalysisTypePanel, 'push');
            app.SingleCycleAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SingleCycleAnalysisButtonPushed, true);
            app.SingleCycleAnalysisButton.Text = 'Single Cycle Analysis';
            app.ParametricAnalysisButton = uibutton(app.AnalysisTypePanel, 'push');
            app.ParametricAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ParametricAnalysisButtonPushed, true);
            app.ParametricAnalysisButton.Text = 'Parametric Analysis';
            app.PerformanceAnalysisButton = uibutton(app.AnalysisTypePanel, 'push');
            app.PerformanceAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformanceAnalysisButtonPushed, true);
            app.PerformanceAnalysisButton.Text = 'Performance Analysis';
            app.GeneralPropertiesPanel = uipanel(app.UIFigure);
            app.GeneralPropertiesPanel.Title = 'General Properties';
            app.SpecificHeatCpEditFieldLabel = uilabel(app.GeneralPropertiesPanel);
            app.SpecificHeatCpEditFieldLabel.Text = 'Specific Heat(Cp)';
            app.SpecificHeatCpEditField = uieditfield(app.GeneralPropertiesPanel, 'numeric');
            app.HeatCapacityRatioLabel = uilabel(app.GeneralPropertiesPanel);
            app.HeatCapacityRatioLabel.Text = 'Heat Capacity Ratio (γ)';
            app.HeatCapacityRatioEditField = uieditfield(app.GeneralPropertiesPanel, 'numeric');
            app.JKgKLabel = uilabel(app.GeneralPropertiesPanel);
            app.JKgKLabel.Text = 'J/Kg.K';
            app.AirGasConstantREditFieldLabel = uilabel(app.GeneralPropertiesPanel);
            app.AirGasConstantREditFieldLabel.Text = 'Air Gas Constant(R)';
            app.AirGasConstantREditField = uieditfield(app.GeneralPropertiesPanel, 'numeric');
            app.JKgKLabel_2 = uilabel(app.GeneralPropertiesPanel);
            app.JKgKLabel_2.Text = 'J/Kg.K';
            app.FuelHeatingCapacityEditFieldLabel = uilabel(app.GeneralPropertiesPanel);
            app.FuelHeatingCapacityEditFieldLabel.Text = 'Fuel Heating Capacity';
            app.FuelHeatingCapacityEditField = uieditfield(app.GeneralPropertiesPanel, 'numeric');
            app.JKgLabel = uilabel(app.GeneralPropertiesPanel);
            app.JKgLabel.Text = 'J/Kg';
            app.ComponentPropertiesPanel = uipanel(app.UIFigure);
            app.ComponentPropertiesPanel.Title = 'Component Properties';
            app.CompressorEfficiencyEditFieldLabel = uilabel(app.ComponentPropertiesPanel);
            app.CompressorEfficiencyEditFieldLabel.Text = 'Compressor Efficiency';
            app.CompressorEfficiencyEditField = uieditfield(app.ComponentPropertiesPanel, 'numeric');
            app.CompressorEfficiencyEditField.Limits = [0 1];
            app.TurbineIsentropicEfficiencyEditFieldLabel = uilabel(app.ComponentPropertiesPanel);
            app.TurbineIsentropicEfficiencyEditFieldLabel.Text = 'Turbine Isentropic Efficiency';
            app.TurbineIsentropicEfficiencyEditField = uieditfield(app.ComponentPropertiesPanel, 'numeric');
            app.TurbineIsentropicEfficiencyEditField.Limits = [0 1];
            app.NozzleEfficiencyEditFieldLabel = uilabel(app.ComponentPropertiesPanel);
            app.NozzleEfficiencyEditFieldLabel.Text = 'Nozzle Efficiency';
            app.NozzleEfficiencyEditField = uieditfield(app.ComponentPropertiesPanel, 'numeric');
            app.NozzleEfficiencyEditField.Limits = [0 1];
            app.TurbineMechanicalEfficiencyEditFieldLabel = uilabel(app.ComponentPropertiesPanel);
            app.TurbineMechanicalEfficiencyEditFieldLabel.Text = 'Turbine Mechanical Efficiency';
            app.TurbineMechanicalEfficiencyEditField = uieditfield(app.ComponentPropertiesPanel, 'numeric');
            app.TurbineMechanicalEfficiencyEditField.Limits = [0 1];
            app.BurnerPressureRatioEditFieldLabel = uilabel(app.ComponentPropertiesPanel);
            app.BurnerPressureRatioEditFieldLabel.Text = 'Burner Pressure Ratio';
            app.BurnerPressureRatioEditField = uieditfield(app.ComponentPropertiesPanel, 'numeric');
            app.BurnerPressureRatioEditField.Limits = [0 1];
            app.m2Label = uilabel(app.ComponentPropertiesPanel);
            app.m2Label.Text = 'm^2';
            app.NozzleExitAreaEditFieldLabel = uilabel(app.ComponentPropertiesPanel);
            app.NozzleExitAreaEditFieldLabel.Text = 'Nozzle Exit Area';
            app.NozzleExitAreaEditField = uieditfield(app.ComponentPropertiesPanel, 'numeric');
            app.PARATHRUSTLabel = uilabel(app.UIFigure);
            app.PARATHRUSTLabel.Text = 'PARA THRUST';
            app.ToolboxPanel = uipanel(app.UIFigure);
            app.ToolboxPanel.Title = 'Toolbox';
            app.SaveInputFileButton = uibutton(app.ToolboxPanel, 'push');
            app.SaveInputFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInputFileButtonPushed, true);
            app.SaveInputFileButton.Text = 'Save Input File';
            app.ReadfromFileButton = uibutton(app.ToolboxPanel, 'push');
            app.ReadfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadfromFileButtonPushed, true);
            app.ReadfromFileButton.Text = 'Read from File';
            app.UnitsPanel = uipanel(app.UIFigure);
            app.UnitsPanel.Title = 'Units';
            app.UNITSSwitch = uiswitch(app.UnitsPanel, 'slider');
            app.UNITSSwitch.Items = {'British', 'Metric'};
            app.UNITSSwitch.ValueChangedFcn = createCallbackFcn(app, @UNITSSwitchValueChanged, true);
            app.UNITSSwitch.Value = 'Metric';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'PBS-TJ100P-UAV-Turbojet-Engine-scaled-transformed.png';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'skynews-typhoon-raf-file-image_5937093-transformed.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAINPAGE(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end