classdef Single_Cycle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image2_2                       matlab.ui.control.Image
        TextFileDownloadButton         matlab.ui.control.Button
        SINGLECYCLEANALYSISLabel       matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        InputParametersPanel           matlab.ui.container.Panel
        KLabel                         matlab.ui.control.Label
        BETEditField                   matlab.ui.control.NumericEditField
        BETEditFieldLabel              matlab.ui.control.Label
        kmLabel                        matlab.ui.control.Label
        AltitudeEditField              matlab.ui.control.NumericEditField
        AltitudeEditFieldLabel         matlab.ui.control.Label
        CompressorRatioEditField       matlab.ui.control.NumericEditField
        CompressorRatioEditFieldLabel  matlab.ui.control.Label
        MachNoEditField                matlab.ui.control.NumericEditField
        MachNoEditFieldLabel           matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        OutputParametersPanel          matlab.ui.container.Panel
        KgsLabel_2                     matlab.ui.control.Label
        KgsLabel                       matlab.ui.control.Label
        daNKgLabel                     matlab.ui.control.Label
        KgdaNhrLabel                   matlab.ui.control.Label
        daNLabel                       matlab.ui.control.Label
        MassFlowRateofFuelEditField    matlab.ui.control.NumericEditField
        MassFlowRateofFuelEditFieldLabel  matlab.ui.control.Label
        MassFlowRateofAirEditField     matlab.ui.control.NumericEditField
        MassFlowRateofAirEditFieldLabel  matlab.ui.control.Label
        SpecificThrustEditField        matlab.ui.control.NumericEditField
        SpecificThrustEditFieldLabel   matlab.ui.control.Label
        SFCEditField                   matlab.ui.control.NumericEditField
        SFCLabel                       matlab.ui.control.Label
        NetThrustEditField             matlab.ui.control.NumericEditField
        NetThrustEditFieldLabel        matlab.ui.control.Label
        RUNButton                      matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        TsTab                          matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        PvTab                          matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        Image2                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        callingapp 
    end
    
    properties (Access = public)
        A1
        A2
        A3
        A4
        A5
        A6
        A7
        A8
        A9
        A10
        A11
        A_temp_array
        A_pressure_array
        A_stations
        A_m_inlet
        A_m_exhaust
        A_m_fuel
        A_net_thrust
        A_SFC
        A_AF_Ratio
        A_PRc
        A_PRt
        A_BET
        A_PRb
        A_Pressure_Temp_Table
        
    end


    methods (Access = public)
        
        function NEW_TJ2(app, mach, comp_r , altitude , bet)


Temp = zeros(6,1);
Press = zeros(6,1);
s_vol = zeros(6,1);
s_entro = zeros(6,1);

%[Po,To,Mo,cp_gas,tau_r,tau_d,tau_c,tau_b,tau_t,tau_n,pi_r,pi_d,pi_c,pi_b,pi_t,pi_n,Ttb_lim,Eff_c,Eff_b,Eff_t,Eff_n] = inputparameters ;

%[Po,To,Mo,mo,cp_gas, gamma_gas] = [100e+3 , 223 , 2 , 32 , 1000 , 1.4];


%-------------------------
Eff_c = app.A1;
PR_burn = app.A5;
Eff_Noz = app.A2;
A_noz = app.A7;
Eff_isen_turb = app.A3;
Eff_mechn_turb = app.A4;
cp_gas = app.A6;  %J/Kg.Kelvin
Qhv = app.A10; %J/kg
gamma_gas = app.A11;
R = app.A8;
%-------------------------


BET = bet;
h = altitude;
Mo = mach;
Tamb = (15.04-(0.00649*h));
Pamb = (101.29* ((Tamb+273.1)/288.08)^5.256);
Velo_sound = (gamma_gas*R*(Tamb + 273))^0.5;
Velo_1 = Mo*Velo_sound;
Dynamic_Temp = (Velo_1^2)/(2*cp_gas);
Pt = Pamb*1000*(1 + Dynamic_Temp/(Tamb + 273))^(gamma_gas/(gamma_gas-1)); %Pascals
Tt = Tamb + 273.16 + Dynamic_Temp; %Kelvin
Po = Pt; %Pascals
To = Tt; %Kelvin


T_for_so= Tamb+273.16;
so  = 1000*(air_table(app,(T_for_so)));


Temp(1,1) = To;
Press(1,1) = Po;
s_entro(1,1) = so;
vo=(R*To)/Po;
s_vol(1,1) = vo;

T1 = To;
p1 = Po;
s1 = so;
v1 = vo;

%Compressor Function Call
Temp(2,1) = T1;
Press(2,1) = p1;
s_entro(2,1) = s1;
s_vol(2,1) = v1;


PR_c = comp_r; 
[ T2 , p2 ,v2 , s2 , W_c ] = Comp_Func (app,T1 , p1 , PR_c , Eff_c , gamma_gas , cp_gas , R , s1);


Temp(3,1) = T2;
Press(3,1) = p2;
s_entro(3,1) = s2;
s_vol(3,1) = v2;

%Burner Function Call


[ T3 ,p3 , v3 , s3,AF] = burner_func (app,  T2 , p2 ,  cp_gas , R , s2,PR_burn,BET,Qhv );


Temp(4,1) = T3;
Press(4,1) = p3;
s_entro(4,1) = s3;
s_vol(4,1) = v3;

%cp_gas = 1004;

%Turbine Function Call


W_t = W_c;

[ T4 , p4 ,v4 , s4 ] = Turb_Func(app, T3 , p3 , Eff_isen_turb , Eff_mechn_turb , W_t , gamma_gas , cp_gas , s3 ,R);


Temp(5,1) = T4;
Press(5,1) = p4;
s_entro(5,1) = s4;
s_vol(5,1) = v4;

%Nozzle Function Call


%PR_noz = .05;


[ m5 , T5 , p5 , Velo_5 , v5 , s5 ] = Nozzle_Func(app, T4 , p4 , Eff_Noz , gamma_gas , cp_gas , s4, R , A_noz);

dyn_temp = ((Velo_5)^2)/(2 * cp_gas);
dyn_press = ((Velo_5)^2)/(v5*2);
Temp(6,1) = T4 ;
Press(6,1) = p4;
s_entro(6,1) = s5;
s_vol(6,1) = v5;

%Thrust Function Call
m_fuel = m5 /(1+AF);
app.MassFlowRateofFuelEditField.Value = (m_fuel)  ;
m_inlet = m5-m_fuel;
app.MassFlowRateofAirEditField.Value  = (m_inlet)  ;

[net_thrust , net_thrust_spec] = thrust_func(app,Velo_1,Velo_5, m5,Po,p5,A_noz,m_inlet);
app.NetThrustEditField.Value = (net_thrust/10)  ;
app.SpecificThrustEditField.Value  =  (net_thrust_spec/10) ;
%SFC Calculation

specific_fuel = m_fuel/net_thrust;
app.SFCEditField.Value = (specific_fuel*3600*10)  ;

stations = [0;1;2;3;4;5];

%Putting in Values into the Global A_Property Variables
%-------------------------------------------------------------

        app.A_temp_array = Temp;
        app.A_pressure_array = Press;
        app.A_stations = stations;
        app.A_m_inlet = m_inlet;
        app.A_m_exhaust = m5;
        app.A_m_fuel = m_fuel;
        app.A_net_thrust = net_thrust;
        app.A_SFC = specific_fuel;
        app.A_AF_Ratio = AF;
        app.A_PRc = PR_c;
        app.A_PRt = p3/p4;
        app.A_BET = BET;
        app.A_PRb = PR_burn;
        app.A_Pressure_Temp_Table(:,1) = stations;
        app.A_Pressure_Temp_Table(:,2) = Temp;
        app.A_Pressure_Temp_Table(:,3) = Press;
        app.A_Pressure_Temp_Table(1:3,4) = m_inlet;
        app.A_Pressure_Temp_Table(4:6,4) = m5;
        
%----------------------------------------------------------


app.UITable.Data(:,1) = stations;
app.UITable.Data(:,2) = Temp;
app.UITable.Data(:,3) = Press;
app.UITable.Data(1:3,4) = m_inlet;
app.UITable.Data(4:6,4) = m5;

% tdata = table(Temp,press,'VariableNames',{'T-K','P-Pa'});
% uit = uitable(fig,'Data',tdata);


%PLOTS

plot(app.UIAxes,  s_entro,Temp ,'-o') 
grid(app.UIAxes, 'on');
title(app.UIAxes, 'Temp vs Specific Entropy')
xlabel(app.UIAxes, 'Specific Entropy / (kJ/kg.K)')
ylabel(app.UIAxes, 'Temperature / K')


plot(app.UIAxes_2,s_vol,Press ,'-o')
grid(app.UIAxes_2, 'on');
title(app.UIAxes_2,'Pressure vs Specific Volume')
xlabel(app.UIAxes_2, 'Specific Volume / (m^3/kg)')
ylabel(app.UIAxes_2, 'Pressure / Pa')

end


%% Attached functions

function s=air_table(app,value)
%the function takes temperature in Kelvin as input
f=importfile('air.xlsx');
[r c]=size(f);%this gives us the size of the matrix containg the data imported from the sheet where r=no.of rows and c=no.of columns of the matrix
for i=1:r %the for loop compares the given value with those in the steam table
    if value>f(end,1)%if the value is greater than the greatest value in the table it gives an error
        error('input is out of bound');
    elseif value<f(1,1)%similarly if it is smaller than the smallest value again an error is generated
        error('input is out of bound');
    elseif value==f(i,1)%if value equals some value from the steam table, all the correspoding values are stored in the different varaible below
        h=f(i,2);
        u=f(i,3);
        s=f(i,4);
        pr=f(i,5);
        vr=f(i,6);
    elseif f(i,1)<value && f(i+1,1)>value %if the value is inbetween any 2 values from the table, the function interpolates all the data (interpolation is done from line 46 to 58)
        h=(((value-f(i,1))/(f(i+1,1)-f(i,1)))*(f(i+1,2)-f(i,2)))+f(i,2);
        u=(((value-f(i,1))/(f(i+1,1)-f(i,1)))*(f(i+1,3)-f(i,3)))+f(i,3);
        s=(((value-f(i,1))/(f(i+1,1)-f(i,1)))*(f(i+1,4)-f(i,4)))+f(i,4);
        pr=(((value-f(i,1))/(f(i+1,1)-f(i,1)))*(f(i+1,5)-f(i,5)))+f(i,5);
        vr=(((value-f(i,1))/(f(i+1,1)-f(i,1)))*(f(i+1,6)-f(i,6)))+f(i,6);
    end
end
    %fprintf('T = %f K\nh = %f kj/kg\nu = %f kj/kg\ns = %f kj/K\npr = %f\nvr = %f\n',value,h,u,s,pr,vr);
    %this last line prints all the properties and their values (all the values are in SI units)
end

function air = importfile(app,workbookFile, sheetName, dataLines)
%IMPORTFILE Import data from a spreadsheet
%  AIR = IMPORTFILE(FILE) reads data from the first worksheet in the
%  Microsoft Excel spreadsheet file named FILE.  Returns the numeric
%  data.
%
%  AIR = IMPORTFILE(FILE, SHEET) reads from the specified worksheet.
%
%  AIR = IMPORTFILE(FILE, SHEET, DATALINES) reads from the specified
%  worksheet for the specified row interval(s). Specify DATALINES as a
%  positive scalar integer or a N-by-2 array of positive scalar integers
%  for dis-contiguous row intervals.
%
%  Example:
%  air = importfile("D:\TurboJet Code\Matlab\air.xlsx", "Sheet1", [5, 126]);
%
%  See also READTABLE.
%
% Auto-generated by MATLAB on 16-Nov-2022 21:59:33

%% Input handling

% If no sheet is specified, read first sheet
if nargin == 1 || isempty(sheetName)
    sheetName = 1;
end

% If row start and end points are not specified, define defaults
if nargin <= 2
    dataLines = [5, 126];
end

%% Setup the Import Options and import the data
opts = spreadsheetImportOptions("NumVariables", 6);

% Specify sheet and range
opts.Sheet = sheetName;
opts.DataRange = "A" + dataLines(1, 1) + ":F" + dataLines(1, 2);

% Specify column names and types
opts.VariableNames = ["VarName1", "VarName2", "VarName3", "VarName4", "whens0", "VarName6"];
opts.VariableTypes = ["double", "double", "double", "double", "double", "double"];

% Import the data
air = readtable(workbookFile, opts, "UseExcel", false);

for idx = 2:size(dataLines, 1)
    opts.DataRange = "A" + dataLines(idx, 1) + ":F" + dataLines(idx, 2);
    tb = readtable(workbookFile, opts, "UseExcel", false);
    air = [air; tb]; %#ok<AGROW>
end

%% Convert to output type
air = table2array(air);
end

function [thrust_net , thrust_s ] = thrust_func(app,Velo_1,Velo_2, m2,Po,p2,A_noz,m_inlet)

%Calculating Thrust

%gross thrust

pressure_thrust = (A_noz)*(p2-Po);

thrust_g = m2*Velo_2;

%Ram Drag

ram_drag = m_inlet*Velo_1;

%Net Thrust

thrust_net = thrust_g + pressure_thrust - ram_drag ;

%Spec Net Thrust

thrust_s = thrust_net/m_inlet;


end

% function [T2,P2,s2,v2] =inlet_func(Velo_1,cp_gas,T1,P1,eff_inlet,s1,R,gamma)
% %delta_t= (Velo_1^2)/(2*cp_gas);
% delta_t = 0;
% T2 = T1+delta_t;
% pratio = (1 + eff_inlet*(delta_t/T1) )^(gamma/(gamma-1));
% P2 = pratio * P1;
% v2= (R*T2)/P2;
% delta_s = cp_gas*log((T2/T1)) - R*log(P2/P1);
% s2 = s1 + delta_s;
% end

    function [T2 , P2 , v2 , s2 , W] = Comp_Func (app, T1 , P1 ,PR , Eff , gamma , Cpa , R, s1)
    % Calculates the massflow , temperature and pressure of a compressor with a
    % given massflow , pressure , pressure ratio , efficiency and using the gamma
    % and CP of air.
    % Calculate Pressure
    P2=PR*P1;
    % Calculate Temperature using Isentropic efficiency
    T2 = T1*(1/Eff)*((P2/P1)^(( gamma -1)/ gamma ) -1) + T1;  
    % Calculate work done by the compressor
    W = Cpa *(T2 -T1 );
    % Calculate v1 at Compressor Outlet
    v2 = (R*T2)/P2;
    % Calculate s1 at Compressor Outlet
    delta_s = Cpa*log((T1/T2)) - R*log(P1/P2);
    s2 = s1 + delta_s;

    end
    
function [ T2 ,P2 , v2, s2, AF ] = burner_func (app, T1 , P1 , Cpg, R, s1, PR_burn, BET, Qhv )
T2=BET;
P2=PR_burn*P1;
% Calculate v2 at Burner Outlet
v2 = (R*T2)/P2;
%AF
delta_T = T2-T1;
AF = (Qhv/(Cpg * delta_T))-1;
% Calculate s2 at Burner Outlet
Delta_s = (Cpg)*log(T2/T1) - (R)*log(P2/P1);
s2 = Delta_s + s1;
end

function [ m2 , T2 , P2 , Velo_2 , v2 , s2 ] = Nozzle_Func(app,  T1 , P1 , Eff_noz , gamma_gas , Cpg ,s1 ,R, A_noz )
% Calculates the massflow , temperature and pressure of an exhaust with a given
%  pressure , pressure ratio , efficiency and using the gamma of gas,
%  Area,entropy at inlet

%Finding Pc
exponent = (gamma_gas)/(gamma_gas-1);
ratio = (1-(1/Eff_noz)*((gamma_gas-1)/(gamma_gas+1)))^exponent ;
Pc = P1*ratio;
%Calculate T2
T2 = T1*(2*(1/(gamma_gas + 1)));
%Calculating exit pressure
P2= P1*(ratio);
%calcuate density
row = Pc/(R*T2);
%calcuate velocity
Velo_2 = (gamma_gas*R*T2)^0.5;
%Calculating mass flow rate
m2 = A_noz * row * Velo_2;
% Calculate v2 at nozzle Outlet
v2 = (R*T2)/P2;
% Calculate s2 at nozzle Outlet
delta_s=Cpg*log((T2/T1))- R*log(P2/P1);
s2 = s1 + delta_s;
end

function [ T2 , P2 , v2, s2 , PR ] = Turb_Func(app, T1 , P1 , Eff_isen , Eff_mechn , W , gamma_gas , Cpg, s1, R)
% Calculates the massflow , temperature and pressure of a turbine with a given
% massflow , pressure , Mechanical and isentropic efficiencies , the work done ,
% the cp of gass and using the gamma of gas
% Calculate temperature difference due to work done

Delta_T = W/(Cpg*Eff_mechn);
%Calculating T2
T2 = T1 - Delta_T;
%Calculate P2 with isentropic effeciency
P2 = (1/Eff_isen)*P1*((T2/T1)^(gamma_gas/(gamma_gas-1)) - 1 ) + P1;

%Calculating Pressure ratio
PR = P1/P2;
% Calculate v2 at Turbine Outlet
v2 = (R*T2)/P2;
% Calculate s2 at Compressor Outlet
delta_s = Cpg*log(T2/T1) - R*log(P2/P1);


s2 = s1 + delta_s;
end


function holding_data(app , cpr_eff,Nozz_eff, Tur_eff, Tur_mech_eff, Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma  )
       
       app.A1 = cpr_eff;
       app.A2=Nozz_eff;
       app.A3=Tur_eff;
       app.A4=Tur_mech_eff;
       app.A5=Burn_eff;
       app.A6=Cp;
       app.A7=nozz_area;
       app.A8=Gas_Constant;
       app.A10=Qhv;
       app.A11=Gamma;
                         
            
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MAINPAGE, cpr_eff, Nozz_eff, Tur_eff, Tur_mech_eff, Burn_eff, Cp, nozz_area, Gas_Constant, Qhv, Gamma)
            app.MachNoEditField.Value = 0.7;
            app.CompressorRatioEditField.Value = 8;
            app.AltitudeEditField.Value = 10;
            app.BETEditField.Value = 1050;
            
            app.callingapp = MAINPAGE;
            
            holding_data(app , cpr_eff,Nozz_eff, Tur_eff, Tur_mech_eff, Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma )
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            cla(app.UIAxes, "reset");
            cla(app.UIAxes_2,"reset");
            
            mach = app.MachNoEditField.Value;
            alt = (app.AltitudeEditField.Value)*1000;
            comp_r = app.CompressorRatioEditField.Value;
            bet = app.BETEditField.Value;
            
            NEW_TJ2(app, mach , comp_r , alt, bet)


        end

        % Button pushed function: TextFileDownloadButton
        function TextFileDownloadButtonPushed(app, event)
            [file,path] = uiputfile('*.txt','Download Single Cycle Results');
            
            full_directory = fullfile(path,file);
            
            f = fopen(full_directory,'w');
            
            
            fprintf(f,'Station No.         T/K                P/Pa                  M_Rate/(kg/s)\n');
            fprintf(f,'%-9.0f           %-9.3f          %-9.3f             %-9.3f\n',app.A_Pressure_Temp_Table.');
            fprintf(f,'\n');
            fprintf(f,'----------------------------------------------------------------------------------------------------------------------\n');
            fprintf(f,'                                              Input Parameters                                                        \n');
            fprintf(f,'Compressor_Eff:%-9.3f            Nozzle_Eff:%-9.3f                           Turbine_Isen_Eff:%-9.3f\n',app.A1,app.A2,app.A3);
            fprintf(f,'Turbine_Mech_Eff:%-9.3f          PR_B:%-9.3f                                 Specific_Heat_Capacity:%-9.3f J/kg.K\n',app.A4,app.A5,app.A6);
            fprintf(f,'Nozzle_Area:%-9.3f m^2           Air_Gas_Constant:%-9.3f J/mol.K             Fuel_Heating_Value:%-9.3f J/kg\n',app.A7,app.A8,app.A10);
            fprintf(f,'Specific_Heat_Ratio:%-9.3f       Burner_Exit_Temp:%-9.3f K\n',app.A11);
            fprintf(f,'\n');
            fprintf(f,'--------------------------------------------------------------------------------------------------------------------\n');
            fprintf(f,'                                              Output Parameters                                                     \n');
            fprintf(f,'M_Fuel:%-9.3f kg/s               M_Exhaust:%-9.3f kg/s                       M_Inlet:%-9.3f kg/s\n',app.A_m_fuel,app.A_m_exhaust,app.A_m_inlet);
            fprintf(f,'Net_Thrust:%-9.3f N              Specific_Fuel_Consumption:%-9.3f kg/N.s     Air_Fuel_Ratio:%-9.3f\n',app.A_net_thrust,app.A_SFC,app.A_AF_Ratio);
            fprintf(f,'PR_C:%-9.3f                      PR_T:%-9.3f\n',app.A_PRc,app.A_PRt);

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'milad-fakurian-UiiHVEyxtyA-unsplash.jpg';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TsTab = uitab(app.TabGroup);
            app.TsTab.Title = 'T-s';
            app.UIAxes = uiaxes(app.TsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PvTab = uitab(app.TabGroup);
            app.PvTab.Title = 'P-v';
            app.UIAxes_2 = uiaxes(app.PvTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.OutputParametersPanel = uipanel(app.UIFigure);
            app.OutputParametersPanel.Title = 'Output Parameters';
            app.NetThrustEditFieldLabel = uilabel(app.OutputParametersPanel);
            app.NetThrustEditFieldLabel.Text = 'Net Thrust';
            app.NetThrustEditField = uieditfield(app.OutputParametersPanel, 'numeric');
            app.SFCLabel = uilabel(app.OutputParametersPanel);
            app.SFCLabel.Text = 'SFC';
            app.SFCEditField = uieditfield(app.OutputParametersPanel, 'numeric');
            app.SpecificThrustEditFieldLabel = uilabel(app.OutputParametersPanel);
            app.SpecificThrustEditFieldLabel.Text = 'Specific Thrust';
            app.SpecificThrustEditField = uieditfield(app.OutputParametersPanel, 'numeric');
            app.MassFlowRateofAirEditFieldLabel = uilabel(app.OutputParametersPanel);
            app.MassFlowRateofAirEditFieldLabel.Text = 'Mass Flow Rate of Air';
            app.MassFlowRateofAirEditField = uieditfield(app.OutputParametersPanel, 'numeric');
            app.MassFlowRateofFuelEditFieldLabel = uilabel(app.OutputParametersPanel);
            app.MassFlowRateofFuelEditFieldLabel.Text = 'Mass Flow Rate of Fuel';
            app.MassFlowRateofFuelEditField = uieditfield(app.OutputParametersPanel, 'numeric');
            app.daNLabel = uilabel(app.OutputParametersPanel);
            app.daNLabel.Text = 'daN';
            app.KgdaNhrLabel = uilabel(app.OutputParametersPanel);
            app.KgdaNhrLabel.Text = 'Kg/daN.hr';
            app.daNKgLabel = uilabel(app.OutputParametersPanel);
            app.daNKgLabel.Text = 'daN/Kg';
            app.KgsLabel = uilabel(app.OutputParametersPanel);
            app.KgsLabel.Text = 'Kg/s';
            app.KgsLabel_2 = uilabel(app.OutputParametersPanel);
            app.KgsLabel_2.Text = 'Kg/s';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Station No.'; 'T - K'; 'P - Pa'; 'Mass - Kg'};
            app.UITable.RowName = {};
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.MachNoEditFieldLabel = uilabel(app.InputParametersPanel);
            app.MachNoEditFieldLabel.Text = 'Mach No.';
            app.MachNoEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.CompressorRatioEditFieldLabel = uilabel(app.InputParametersPanel);
            app.CompressorRatioEditFieldLabel.Text = 'Compressor Ratio';
            app.CompressorRatioEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.AltitudeEditFieldLabel = uilabel(app.InputParametersPanel);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.kmLabel = uilabel(app.InputParametersPanel);
            app.kmLabel.Text = 'km';
            app.BETEditFieldLabel = uilabel(app.InputParametersPanel);
            app.BETEditFieldLabel.Text = 'BET';
            app.BETEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.KLabel = uilabel(app.InputParametersPanel);
            app.KLabel.Text = 'K';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'THE Labelled ENGINE.png';
            app.SINGLECYCLEANALYSISLabel = uilabel(app.UIFigure);
            app.SINGLECYCLEANALYSISLabel.Text = 'SINGLE CYCLE ANALYSIS';
            app.TextFileDownloadButton = uibutton(app.UIFigure, 'push');
            app.TextFileDownloadButton.ButtonPushedFcn = createCallbackFcn(app, @TextFileDownloadButtonPushed, true);
            app.TextFileDownloadButton.Text = 'DOWNLOAD RESULTS';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'skynews-typhoon-raf-file-image_5937093-transformed.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Single_Cycle(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end