classdef para_test_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image2                      matlab.ui.control.Image
        GraphDropDown               matlab.ui.control.DropDown
        GraphDropDownLabel          matlab.ui.control.Label
        ExportDatatoExcelButton     matlab.ui.control.Button
        DownloadFigureButton        matlab.ui.control.Button
        PARAMETRICANALYSISLabel     matlab.ui.control.Label
        CROPFIGUREButton            matlab.ui.control.Button
        EngineSelectionPanel        matlab.ui.container.Panel
        MINEditField_2              matlab.ui.control.NumericEditField
        MINEditField_2Label         matlab.ui.control.Label
        MAXEditField_2              matlab.ui.control.NumericEditField
        MAXEditField_2Label         matlab.ui.control.Label
        NetThrustLabel              matlab.ui.control.Label
        MINEditField                matlab.ui.control.NumericEditField
        MINEditFieldLabel           matlab.ui.control.Label
        SFCLabel                    matlab.ui.control.Label
        MAXEditField                matlab.ui.control.NumericEditField
        MAXEditFieldLabel           matlab.ui.control.Label
        ConstantParameterPanel      matlab.ui.container.Panel
        BETKLabel                   matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        BETEditField                matlab.ui.control.NumericEditField
        EditField                   matlab.ui.control.NumericEditField
        CompressorRatioLabel        matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        NTALTTab                    matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        SFCNTTab                    matlab.ui.container.Tab
        UIAxes_2                    matlab.ui.control.UIAxes
        SNTALTTab                   matlab.ui.container.Tab
        UIAxes_3                    matlab.ui.control.UIAxes
        SFCALTTab                   matlab.ui.container.Tab
        UIAxes_4                    matlab.ui.control.UIAxes
        MALTTab                     matlab.ui.container.Tab
        UIAxes_5                    matlab.ui.control.UIAxes
        RUNButton                   matlab.ui.control.Button
        VariableParametersPanel     matlab.ui.container.Panel
        Parameter2DropDown          matlab.ui.control.DropDown
        Parameter2DropDownLabel     matlab.ui.control.Label
        Parameter1DropDown          matlab.ui.control.DropDown
        Parameter1DropDownLabel     matlab.ui.control.Label
        StartValueEditField_2       matlab.ui.control.NumericEditField
        StartValueEditField_2Label  matlab.ui.control.Label
        EndValueEditField_2         matlab.ui.control.NumericEditField
        EndValueEditField_2Label    matlab.ui.control.Label
        StepSizeEditField_2         matlab.ui.control.NumericEditField
        StepSizeEditField_2Label    matlab.ui.control.Label
        StepSizeEditField           matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel      matlab.ui.control.Label
        EndValueEditField           matlab.ui.control.NumericEditField
        EndValueEditFieldLabel      matlab.ui.control.Label
        StartValueEditField         matlab.ui.control.NumericEditField
        StartValueEditFieldLabel    matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        callingapp 
    end
    
    properties (Access = public)
        A1
        A2
        A3
        A4
        A5
        A6
        A7
        A8
        A10
        A11
        comp_array
        nt_array
        snt_attay
        sfc
        mach_array
        alt_array
        air_array
        BET_arr
        Data_1
        Data_2
        Data_3
        Data_4
        Data_5
        
        
    end
    
    methods (Access = public)


                                                    %% MACH & COMP
            
            function [net_thrust_array , sfc_array] = parametric_comp(app, comp_1, comp_2, comp_step, mach_1 , mach_2 , mach_step,v3,v4)
num = ((comp_2 - comp_1)/comp_step) + 1;
Temp = zeros(6,1);
Press = zeros(6,1);
net_thrust_array = zeros(1,num);
spec_net_thrust_array = zeros(1,num);
sfc_array = zeros(1,num);

comp_ratio_array = zeros(1,num);
air_mass_array = zeros(1,num);

i = 1;
j = 1;


h = v3;
BET=v4;

Eff_c = app.A1;

PR_burn = app.A5;
Eff_Noz = app.A2;
A_noz = app.A7;
Eff_isen_turb = app.A3;
Eff_mechn_turb = app.A4;
cp_gas = app.A6;  %J/Kg.Kelvin
Qhv = app.A10; %J/kg
gamma_gas = app.A11;
R = app.A8;

%----------------------------------------------
if h <=11000
        Tamb = (15.04-(0.00649*h));
        Pamb = (101.29* ((Tamb+273.1)/288.08)^5.256);
    elseif h >11000 && h <25000
            Tamb = -56.46;
            Pamb = 22.65 * (exp(1.73-(.000157*h)));
        else 
            Tamb = -131.21 + (.00299 * h);
            Pamb = 2.488 * (((Tamb+273.1)/(216.6))^-11.388);
end
%---------------------------------------------

Velo_sound = (gamma_gas*R*(Tamb + 273))^0.5;


for mach = mach_1:mach_step:mach_2
Mo = mach;
j = 1;
Velo_1 = Mo*Velo_sound;
Dynamic_Temp = (Velo_1^2)/(2*cp_gas);


Pt = Pamb*1000*(1 + Dynamic_Temp/(Tamb + 273))^(gamma_gas/(gamma_gas-1)); %Pascals
Tt = Tamb + 273.16 + Dynamic_Temp; %Kelvin

Po = Pt ;%Pascals
To = Tt ;%Kelvin

%Po = Pamb*1000 %Pascals
%To = Tamb + 273.16 %Kelvin
so  = 1391; % remove

Temp(1,1) = To;
Press(1,1) = Po;
for comp_ratio = comp_1:comp_step:comp_2


PR_c = comp_ratio;
comp_ratio_array(i,j) = PR_c;
Velo_1 = Mo*Velo_sound;

%Compressor Function Call
Temp(2,1) = To;
Press(2,1) = Po;
[ T2 , p2  ,W_c ] = Comp_Func (app,To , Po , PR_c , Eff_c , gamma_gas , cp_gas);
Temp(3,1) = T2;
Press(3,1) = p2;

%Burner Function Call
[ T3 ,p3 ,AF ] = burner_func ( app, T2 , p2 ,  cp_gas ,PR_burn,BET,Qhv );

Temp(4,1) = T3;
Press(4,1) = p3;

%Turbine Function Call

W_t = W_c;

[ T4 , p4 ] = Turb_Func(app, T3 , p3 , Eff_isen_turb , Eff_mechn_turb , W_t , gamma_gas , cp_gas);

Temp(5,1) = T4;
Press(5,1) = p4;

%Nozzle Function Call

[ m5 , T5 , p5 , Velo_5 ] = Nozzle_Func(app, T4 , p4 , Eff_Noz , gamma_gas , A_noz,R);

Temp(6,1) = T5;
Press(6,1) = p5;

m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;

%Thrust Function Call
[net_thrust , net_thrust_spec] = thrust_func(app, Velo_1,Velo_5, m5,Po,p5,A_noz,m_inlet);
net_thrust_array(i,j) = net_thrust;
spec_net_thrust_array(i,j) = net_thrust_spec;

%SFC Calculation
m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;
Theeta= To/(273.16+15);
Pressure_Theeta = Po/(101325);
m_sea = m_inlet * (((Theeta)^0.5)/(Pressure_Theeta));
air_mass_array(i,j) = m_inlet;
specific_fuel = m_fuel/net_thrust;
sfc_array(i,j) = specific_fuel;
fprintf("-----------------------------------------------------");


j = j + 1;

end

        app.comp_array = comp_ratio_array;
        app.nt_array = net_thrust_array;
        app.snt_attay = spec_net_thrust_array;
        app.sfc = sfc_array;
        app.air_array = air_mass_array;

%PLOTS

%figure(1)
plot(app.UIAxes, comp_ratio_array(i,:), net_thrust_array(i,:) ,'-o')
hold(app.UIAxes,'on');
grid(app.UIAxes,'on');
title(app.UIAxes,'Net Thrust vs Compressor Pressure Ratio vs Mach Number')
xlabel(app.UIAxes,'Compression Ratio ')
ylabel(app.UIAxes,'Net Thrust / N ')
Comb_1 = cat(2,net_thrust_array(i,:).', comp_ratio_array(i,:).');
app.Data_1 = cat(2,app.Data_1,Comb_1);

%figure(2)
plot(app.UIAxes_2, net_thrust_array(i,:), sfc_array(i,:) ,'-o')
hold(app.UIAxes_2,'on');
grid(app.UIAxes_2,'on');
title(app.UIAxes_2,'Specific Fuel Consumption vs Net Thrust')
xlabel(app.UIAxes_2,'Net Thrust / N ')
ylabel(app.UIAxes_2,'Specific Fuel Comsumption (SFC) / (kg/s.N) ')
Comb_2 = cat(2,sfc_array(i,:).',net_thrust_array(i,:).' );
app.Data_2 = cat(2,app.Data_2,Comb_2);

%figure(3)
plot( app.UIAxes_3, comp_ratio_array(i,:) , spec_net_thrust_array(i,:) ,'-o')
hold(app.UIAxes_3,'on');
grid(app.UIAxes_3,'on');
title(app.UIAxes_3,'Specific Net Thrust vs Compressor Pressure Ratio vs Mach Number')
xlabel(app.UIAxes_3,'Compression Ratio ')
ylabel(app.UIAxes_3,'Spec Net Thrust / (N.s/kg) ')
Comb_3 = cat(2,spec_net_thrust_array(i,:).', comp_ratio_array(i,:).');
app.Data_3 = cat(2,app.Data_3,Comb_3);

%figure(4)
plot(app.UIAxes_4, comp_ratio_array(i,:) , sfc_array(i,:) ,'-o')
hold(app.UIAxes_4,'on');
grid(app.UIAxes_4,'on');
title(app.UIAxes_4, 'Specific Fuel Comsumption vs Compressor Pressure Ratio vs Mach Number')
xlabel(app.UIAxes_4,'Compression Ratio ')
ylabel(app.UIAxes_4,'Specific Fuel Comsumption (SFC) / (kg/s.N) ')
Comb_4 = cat(2,sfc_array(i,:).', comp_ratio_array(i,:).');
app.Data_4 = cat(2,app.Data_4,Comb_4);

%figure(5)
plot(app.UIAxes_5, comp_ratio_array(i,:) , air_mass_array(i,:) ,'-o')
hold(app.UIAxes_5,'on');
grid(app.UIAxes_5,'on');
title(app.UIAxes_5, 'Inlet Air Flow vs Compressor Pressure Ratio vs Mach Number')
xlabel(app.UIAxes_5, 'Compression Ratio ')
ylabel(app.UIAxes_5, 'Inlet Air Mass Flowrate / (kg/s) ')
Comb_5 = cat(2,air_mass_array.', comp_ratio_array(i,:).');
app.Data_5 = cat(2,app.Data_5,Comb_5);

i = i+1;


end

max_sfc = max(sfc_array,[],"all");
min_sfc = min(sfc_array,[],"all");
max_netthrust = max(net_thrust_array,[],"all");
min_netthrust = min(net_thrust_array,[],"all");

app.MAXEditField.Value = max_sfc;
app.MINEditField.Value = min_sfc;
app.MAXEditField_2.Value = max_netthrust;
app.MINEditField_2.Value = min_netthrust;

sfc_trans = sfc_array.';
net_thrust_trans = net_thrust_array.';

for x = 1:num
    for y = 1:i

plot(app.UIAxes_2,  net_thrust_trans(x,:), sfc_trans(x,:) ,'-o')
hold(app.UIAxes_2,'on');    
    end
end

end

              %% MACH & BET
            
            function [net_thrust_array , sfc_array] = parametric_BET(app, BET1, BET2, BET_step, mach_1 , mach_2 , mach_step,v3,v4)
num = ((BET2 - BET1)/BET_step)+1;
Temp = zeros(6,1);
Press = zeros(6,1);
net_thrust_array = zeros(1,num);
spec_net_thrust_array = zeros(1,num);
sfc_array = zeros(1,num);
BET_array = zeros(1,num);
air_mass_array = zeros(1,num);

i = 1;
j = 1;


comp_ratio = v3;
h = v4;


Eff_c = app.A1; 
PR_burn = app.A5;
Eff_Noz = app.A2;
A_noz = app.A7;
Eff_isen_turb = app.A3;
Eff_mechn_turb = app.A4;
cp_gas = app.A6;  %J/Kg.Kelvin
Qhv = app.A10; %J/kg
gamma_gas = app.A11;
R = app.A8;

%----------------------------------------------
if h <=11000
        Tamb = (15.04-(0.00649*h));
        Pamb = (101.29* ((Tamb+273.1)/288.08)^5.256);
    elseif h >11000 && h <25000
            Tamb = -56.46;
            Pamb = 22.65 * (exp(1.73-(.000157*h)));
        else 
            Tamb = -131.21 + (.00299 * h);
            Pamb = 2.488 * (((Tamb+273.1)/(216.6))^-11.388);
end
%---------------------------------------------

Velo_sound = (gamma_gas*R*(Tamb + 273))^0.5;


for mach = mach_1:mach_step:mach_2
Mo = mach;
j = 1;
Velo_1 = Mo*Velo_sound;
Dynamic_Temp = (Velo_1^2)/(2*cp_gas);


Pt = Pamb*1000*(1 + Dynamic_Temp/(Tamb + 273))^(gamma_gas/(gamma_gas-1)); %Pascals
Tt = Tamb + 273.16 + Dynamic_Temp; %Kelvin

Po = Pt ;%Pascals
To = Tt ;%Kelvin

%Po = Pamb*1000 %Pascals
%To = Tamb + 273.16 %Kelvin
so  = 1391; % remove

Temp(1,1) = To;
Press(1,1) = Po;
for BET = BET1:BET_step:BET2


PR_c = comp_ratio;
BET_array(i,j) = BET;
Velo_1 = Mo*Velo_sound;

%Compressor Function Call
Temp(2,1) = To;
Press(2,1) = Po;
[ T2 , p2  ,W_c ] = Comp_Func (app,To , Po , PR_c , Eff_c , gamma_gas , cp_gas);
Temp(3,1) = T2;
Press(3,1) = p2;

%Burner Function Call
[ T3 ,p3 ,AF ] = burner_func ( app, T2 , p2 ,  cp_gas ,PR_burn,BET,Qhv );

Temp(4,1) = T3;
Press(4,1) = p3;

%Turbine Function Call

W_t = W_c;

[ T4 , p4 ] = Turb_Func(app, T3 , p3 , Eff_isen_turb , Eff_mechn_turb , W_t , gamma_gas , cp_gas);

Temp(5,1) = T4;
Press(5,1) = p4;

%Nozzle Function Call

[ m5 , T5 , p5 , Velo_5 ] = Nozzle_Func(app, T4 , p4 , Eff_Noz , gamma_gas , A_noz,R);

Temp(6,1) = T5;
Press(6,1) = p5;

m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;

%Thrust Function Call
[net_thrust , net_thrust_spec] = thrust_func(app, Velo_1,Velo_5, m5,Po,p5,A_noz,m_inlet);
net_thrust_array(i,j) = net_thrust;
spec_net_thrust_array(i,j) = net_thrust_spec;

%SFC Calculation
m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;
Theeta= To/(273.16+15);
Pressure_Theeta = Po/(101325);
m_sea = m_inlet * (((Theeta)^0.5)/(Pressure_Theeta));
air_mass_array(i,j) = m_inlet;
specific_fuel = m_fuel/net_thrust;
sfc_array(i,j) = specific_fuel;
fprintf("-----------------------------------------------------");


j = j + 1;

end

        app.BET_arr = BET_array;
        app.nt_array = net_thrust_array;
        app.snt_attay = spec_net_thrust_array;
        app.sfc = sfc_array;
        app.air_array = air_mass_array;

%PLOTS

%figure(1)
plot(app.UIAxes, BET_array(i,:), net_thrust_array(i,:) ,'-o')
hold(app.UIAxes,'on');
grid(app.UIAxes,'on');
title(app.UIAxes,'Net Thrust vs BET vs Mach Number')
xlabel(app.UIAxes,'Burner Exit Temperature ')
ylabel(app.UIAxes,'Net Thrust / N ')
Comb_1 = cat(2,net_thrust_array(i,:).', BET_array(i,:).');
app.Data_1 = cat(2,app.Data_1,Comb_1);

%figure(2)
plot(app.UIAxes_2, net_thrust_array(i,:), sfc_array(i,:) ,'-o')
hold(app.UIAxes_2,'on');
grid(app.UIAxes_2,'on');
title(app.UIAxes_2,'Specific Fuel Consumption vs Net Thrust')
xlabel(app.UIAxes_2,'Net Thrust / N ')
ylabel(app.UIAxes_2,'Specific Fuel Comsumption (SFC) / kg/s/N ')
Comb_2 = cat(2,sfc_array(i,:).',net_thrust_array(i,:).' );
app.Data_2 = cat(2,app.Data_2,Comb_2);

%figure(3)
plot( app.UIAxes_3, BET_array(i,:) , spec_net_thrust_array(i,:) ,'-o')
hold(app.UIAxes_3,'on');
grid(app.UIAxes_3,'on');
title(app.UIAxes_3,'Specific Net Thrust vs BET vs Mach Number')
xlabel(app.UIAxes_3,'Burner Exit Temperature')
ylabel(app.UIAxes_3,'Spec Net Thrust / N.s/kg ')
Comb_3 = cat(2,spec_net_thrust_array(i,:).', BET_array(i,:).');
app.Data_3 = cat(2,app.Data_3,Comb_3);

%figure(4)
plot(app.UIAxes_4, BET_array(i,:) , sfc_array(i,:) ,'-o')
hold(app.UIAxes_4,'on');
grid(app.UIAxes_4,'on');
title(app.UIAxes_4, 'Specific Fuel Comsumption vs BET vs Mach Number')
xlabel(app.UIAxes_4,'Burner Exit Temperature ')
ylabel(app.UIAxes_4,'Specific Fuel Comsumption (SFC) / kg/s/N ')
Comb_4 = cat(2,sfc_array(i,:).', BET_array(i,:).');
app.Data_4 = cat(2,app.Data_4,Comb_4);

%figure(5)
plot(app.UIAxes_5, BET_array(i,:) , air_mass_array(i,:) ,'-o')
hold(app.UIAxes_5,'on');
grid(app.UIAxes_5,'on');
title(app.UIAxes_5, 'Inlet Air Flow vs BET vs Mach Number')
xlabel(app.UIAxes_5, 'Burner Exit Temperature ')
ylabel(app.UIAxes_5, 'Inlet Air Mass Flowrate / kg/s ')
Comb_5 = cat(2,air_mass_array.', BET_array(i,:).');
app.Data_5 = cat(2,app.Data_5,Comb_5);

i = i+1;


end

max_sfc = max(sfc_array,[],"all");
min_sfc = min(sfc_array,[],"all");
max_netthrust = max(net_thrust_array,[],"all");
min_netthrust = min(net_thrust_array,[],"all");

app.MAXEditField.Value = max_sfc;
app.MINEditField.Value = min_sfc;
app.MAXEditField_2.Value = max_netthrust;
app.MINEditField_2.Value = min_netthrust;

sfc_trans = sfc_array.';
net_thrust_trans = net_thrust_array.';

for x = 1:num
    for y = 1:i
    %figure(2)
plot(app.UIAxes_2,  net_thrust_trans(x,:), sfc_trans(x,:) ,'-o')
hold(app.UIAxes_2,'on');    
    end
end

end


 %------------------------------------------------------------------------------------------------------------       
                                                %% MACH & ALT
 
 
        function [net_thrust_array , sfc_array] = para_alt(app, h1, h2, h_step, mach_1 , mach_2, mach_step,v3,v4)
num = ((h2 - h1)/h_step)+1;
Temp = zeros(6,1);
Press = zeros(6,1);
net_thrust_array = zeros(1,num);
spec_net_thrust_array = zeros(1,num);
sfc_array = zeros(1,num);
height_array = zeros(1,num);
air_mass_array = zeros(1,num);

i = 1;
j = 1;


Eff_c = app.A1;

PR_burn = app.A5;
Eff_Noz = app.A2;
A_noz = app.A7;
Eff_isen_turb = app.A3;
Eff_mechn_turb = app.A4;
cp_gas = app.A6;  %J/Kg.Kelvin
Qhv = app.A10; %J/kg
gamma_gas = app.A11;
R = app.A8;


comp_ratio = v3;
BET= v4;


for mach = mach_1:mach_step:mach_2
j = 1;
for height = h1:h_step:h2
    Mo = mach;
    h = height;
   %----------------------------------------------
if h <=11000
        Tamb = (15.04-(0.00649*h));
        Pamb = (101.29* ((Tamb+273.1)/288.08)^5.256);
    elseif h >11000 && h <25000
            Tamb = -56.46;
            Pamb = 22.65 * (exp(1.73-(.000157*h)));
        else 
            Tamb = -131.21 + (.00299 * h);
            Pamb = 2.488 * (((Tamb+273.1)/(216.6))^-11.388);
end
%---------------------------------------------
    Velo_sound = (gamma_gas*R*(Tamb + 273))^0.5;
    
Velo_1 = Mo*Velo_sound;
Dynamic_Temp = (Velo_1^2)/(2*cp_gas);


Pt = Pamb*1000*(1 + Dynamic_Temp/(Tamb + 273))^(gamma_gas/(gamma_gas-1)); %Pascals
Tt = Tamb + 273.16 + Dynamic_Temp; 
%Kelvin

Po = Pt ;%Pascals
To = Tt ;%Kelvin

%Po = Pamb*1000 %Pascals
%To = Tamb + 273.16 %Kelvin
so  = 1391; % This needs to be automated

Temp(1,1) = To;
Press(1,1) = Po;
%s_entro(1,1) = so;
vo=(R*To)/Po;
s_vol(1,1) = vo;
h = height;
    Tamb = (15.04-(0.00649*h));
    Pamb = (101.29* ((Tamb+273.1)/288.08)^5.256);

PR_c = comp_ratio;
height_array(i,j) = height;
Velo_1 = Mo*Velo_sound;

%Compressor Function Call
Temp(2,1) = To;
Press(2,1) = Po;
[ T2 , p2  ,W_c ] = Comp_Func (app,  To , Po , PR_c , Eff_c , gamma_gas , cp_gas);
Temp(3,1) = T2;
Press(3,1) = p2;

T2
p2  
W_c

%Burner Function Call
[ T3 ,p3 ,AF ] = burner_func (app,  T2 , p2 ,  cp_gas ,PR_burn,BET,Qhv );

Temp(4,1) = T3;
Press(4,1) = p3;

T3 
p3 
AF

%Turbine Function Call

W_t = W_c;

[ T4 , p4 ] = Turb_Func(app, T3 , p3 , Eff_isen_turb , Eff_mechn_turb , W_t , gamma_gas , cp_gas);

Temp(5,1) = T4;
Press(5,1) = p4;
T4 
p4

%Nozzle Function Call

[ m5 , T5 , p5 , Velo_5 ] = Nozzle_Func( app, T4 , p4 , Eff_Noz , gamma_gas , A_noz,R);

Temp(6,1) = T5;
Press(6,1) = p5;

m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;
m5 
T5 
p5 
Velo_5

%Thrust Function Call
[net_thrust , net_thrust_spec] = thrust_func(app, Velo_1,Velo_5, m5,Po,p5,A_noz,m_inlet);
net_thrust_array(i,j) = net_thrust;
spec_net_thrust_array(i,j) = net_thrust_spec;
net_thrust 
net_thrust_spec

%SFC Calculation
m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;
Theeta= To/(273.16+15);
Pressure_Theeta = Po/(101325);
m_sea = m_inlet * (((Theeta)^0.5)/(Pressure_Theeta));
air_mass_array(i,j) = m_inlet;
specific_fuel = m_fuel/net_thrust;
sfc_array(i,j) = specific_fuel;
fprintf("-----------------------------------------------------");

j = j + 1;

end


        app.alt_array = height_array.';
        app.nt_array = net_thrust_array.';
        app.snt_attay = spec_net_thrust_array.';
        app.sfc = sfc_array.';
        app.air_array = air_mass_array.';
        

%PLOTS


plot(app.UIAxes, height_array(i,:)/1000, net_thrust_array(i,:) ,'-o')
hold(app.UIAxes, 'on')
grid(app.UIAxes, 'on')
title(app.UIAxes, 'Net Thrust vs Altitude vs Mach Number')
xlabel(app.UIAxes, 'Altitude / km ')
ylabel(app.UIAxes, 'Net Thrust / N ')
Comb_1 = cat(2,net_thrust_array(i,:).', height_array(i,:).');
app.Data_1 = cat(2,app.Data_1,Comb_1);


plot(app.UIAxes_2,net_thrust_array(i,:), sfc_array(i,:) ,'-o')
hold(app.UIAxes_2, 'on')
grid(app.UIAxes_2, 'on')
title(app.UIAxes_2, 'Specific Fuel Consumption vs Net Thrust')
xlabel(app.UIAxes_2, 'Net Thrust / N ')
ylabel(app.UIAxes_2, 'Specific Fuel Comsumption (SFC) / kg/s/N ')
Comb_2 = cat(2,sfc_array(i,:).',net_thrust_array(i,:).' );
app.Data_2 = cat(2,app.Data_2,Comb_2);


plot(app.UIAxes_3, height_array(i,:)/1000 , spec_net_thrust_array(i,:) ,'-o')
hold(app.UIAxes_3, 'on')
grid(app.UIAxes_3, 'on')
title(app.UIAxes_3, 'Specific Net Thrust vs Altitude vs Mach Number')
xlabel(app.UIAxes_3, 'Altitude / km')
ylabel(app.UIAxes_3, 'Spec Net Thrust / N.s/kg ')
Comb_3 = cat(2,spec_net_thrust_array(i,:).', height_array(i,:).');
app.Data_3 = cat(2,app.Data_3,Comb_3);


plot(app.UIAxes_4, height_array(i,:)/1000 , sfc_array(i,:) ,'-o')
hold(app.UIAxes_4, 'on')
grid(app.UIAxes_4, 'on')
title(app.UIAxes_4, 'Specific Fuel Comsumption vs Altitude vs Mach Number')
xlabel(app.UIAxes_4, 'Altitude / km')
ylabel(app.UIAxes_4, 'Specific Fuel Comsumption (SFC) / kg/s/N ')
Comb_4 = cat(2,sfc_array(i,:).', height_array(i,:).');
app.Data_4 = cat(2,app.Data_4,Comb_4);


plot(app.UIAxes_5, height_array(i,:)/1000 , air_mass_array(i,:) ,'-o')
hold(app.UIAxes_5, 'on')
grid(app.UIAxes_5, 'on')
title(app.UIAxes_5, 'Inlet Air Flow vs Altitude vs Mach Number')
xlabel(app.UIAxes_5, 'Altitude / km')
ylabel(app.UIAxes_5, 'Inlet Air Mass Flowrate / kg/s ')
Comb_5 = cat(2,air_mass_array.', height_array(i,:).');
app.Data_5 = cat(2,app.Data_5,Comb_5);

i = i+1;


end

max_sfc = max(sfc_array,[],"all");
min_sfc = min(sfc_array,[],"all");
max_netthrust = max(net_thrust_array,[],"all");
min_netthrust = min(net_thrust_array,[],"all");

app.MAXEditField.Value = max_sfc;
app.MINEditField.Value = min_sfc;
app.MAXEditField_2.Value = max_netthrust;
app.MINEditField_2.Value = min_netthrust;


sfc_trans = sfc_array.';
net_thrust_trans = net_thrust_array.';

for x = 1:num
    for y = 1:j
    
plot(app.UIAxes_2, net_thrust_trans(x,:), sfc_trans(x,:) ,'-o')
hold(app.UIAxes_2, 'on')   
    end
end

        end
        
    %----------------------------------------------------------------------------------------------------------    
        
        
    %% Fucntions Attached

    
function [T2 , P2, W] = Comp_Func ( app, T1 , P1 ,PR , Eff , gamma , Cpa )
    % Calculates the massflow , temperature and pressure of a compressor with a
    % given massflow , pressure , pressure ratio , efficiency and using the gamma
    % and CP of air.
    % Calculate Pressure
    P2=PR*P1;
    % Calculate Temperature using Isentropic efficiency
    T2 = T1*(1/Eff)*((P2/P1)^(( gamma -1)/ gamma ) -1) + T1;
    % Calculate work done by the compressor
    W=Cpa *(T2 -T1 );
end
    function [ T2 ,P2 , AF ] = burner_func ( app, T1 , P1 , Cpg, PR_burn, BET, Qhv )

T2=BET;
P2=PR_burn*P1;
%AF
delta_T = T2-T1;
AF = (Qhv/(Cpg * delta_T))-1;
    end
    
function [ m2 , T2 , P2 , Velo_2 ] = Nozzle_Func(app,  T1, P1, Eff_noz, gamma_gas, A_noz,R )
% Calculates the massflow , temperature and pressure of an exhaust with a given
%  pressure , pressure ratio , efficiency and using the gamma of gas,
%  Area,entropy at inlet

%Finding Pc
exponent = (gamma_gas)/(gamma_gas-1);
ratio = (1-(1/Eff_noz)*((gamma_gas-1)/(gamma_gas+1)))^exponent ;
Pc = P1*ratio;
%Calculate T2
T2 = T1*(2*(1/(gamma_gas + 1)));

%Calculating exit pressure
P2= P1*(ratio);

%calcuate density
row = Pc/(R*T2);
%calcuate velocity
Velo_2 = (gamma_gas*R*T2)^0.5;
%Calculating mass flow rate
m2 = A_noz * row * Velo_2;

end

function [ T2 , P2 ] = Turb_Func(app,  T1 , P1 , Eff_isen , Eff_mechn , W , gamma_gas , Cpg )
% Calculates the massflow , temperature and pressure of a turbine with a given
% massflow , pressure , Mechanical and isentropic efficiencies , the work done ,
% the cp of gass and using the gamma of gas
% Calculate temperature difference due to work done
Delta_T = W/(Cpg*Eff_mechn);
%Calculating T2
T2 = T1 - Delta_T;
%Calculate P2 with isentropic effeciency
P2 = (1/Eff_isen)*P1*((T2/T1)^(gamma_gas/(gamma_gas-1)) - 1 ) + P1;
end

function [thrust_net , thrust_s] = thrust_func(app, Velo_1,Velo_2, m2,Po,p2,A_noz,m_inlet)

%Calculating Thrust

%gross thrust

pressure_thrust = (A_noz)*(p2-Po);

thrust_g = m2*Velo_2;

%Ram Drag

ram_drag = m_inlet*Velo_1;

%Net Thrust

thrust_net = thrust_g + pressure_thrust - ram_drag ;

%Spec Net Thrust

thrust_s = thrust_net/m_inlet;

end
        
   %------------------------------------------------------------------------------------------------------------     
 
        
   function holding_data(app , cpr_eff,Nozz_eff, Tur_eff, Tur_mech_eff, Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma  )
       
       app.A1 = cpr_eff;
       app.A2=Nozz_eff;
       app.A3=Tur_eff;
       app.A4=Tur_mech_eff;
       app.A5=Burn_eff;
       app.A6=Cp;
       app.A7=nozz_area;
       app.A8=Gas_Constant;
       app.A10=Qhv;
       app.A11=Gamma;
                         
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MAINPAGE, cpr_eff, Nozz_eff, Tur_eff, Tur_mech_eff, Burn_eff, Cp, nozz_area, Gas_Constant, Qhv, Gamma)
            app.StartValueEditField.Value = 0.3;
            app.EndValueEditField.Value = 0.8;
            app.StepSizeEditField.Value = 0.1;
            
             switch app.Parameter2DropDown.Value
                
                case 'Compressor Ratio'
            app.StartValueEditField_2.Value = 3;
            app.EndValueEditField_2.Value = 8;
            app.StepSizeEditField_2.Value = 1;
            
            app.EditField.Value = 15;
            app.BETEditField.Value = 1050;
            
                 case 'Altitude - km'
            
            app.StartValueEditField_2.Value = 7;
            app.EndValueEditField_2.Value = 15;
            app.StepSizeEditField_2.Value = 1;
            
            app.EditField.Value = 6;
            app.BETEditField.Value = 1050;
            
                 case 'BET'
            
            app.StartValueEditField_2.Value = 1050;
            app.EndValueEditField_2.Value = 1200;
            app.StepSizeEditField_2.Value = 50;
            
            app.EditField.Value = 6;
            app.BETEditField.Value = 12;
            
             end
            
            app.callingapp = MAINPAGE;
            
            holding_data(app , cpr_eff,Nozz_eff, Tur_eff, Tur_mech_eff, Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma )


        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            app.Data_1 = [];
            app.Data_2 = [];
            app.Data_3 = [];
            app.Data_4 = [];
            app.Data_5 = [];


                cla(app.UIAxes, "reset");
                cla(app.UIAxes_2,"reset");
                cla(app.UIAxes_3,"reset");
                cla(app.UIAxes_4,"reset");
                cla(app.UIAxes_5,"reset");
                

            switch app.Parameter2DropDown.Value
                
                case 'Compressor Ratio'
                    
            v1_start = app.StartValueEditField.Value;
            v1_end = app.EndValueEditField.Value;
            v1_step = app.StepSizeEditField.Value;
            
            v2_start = app.StartValueEditField_2.Value;
            v2_end = app.EndValueEditField_2.Value;
            v2_step = app.StepSizeEditField_2.Value;
            
            v3 = 1000 * app.EditField.Value;
            v4 = app.BETEditField.Value;
            
                   parametric_comp(app, v2_start, v2_end, v2_step, v1_start , v1_end , v1_step,v3,v4) 
            
                case 'Altitude - km'
                    
            v1_start = app.StartValueEditField.Value;
            v1_end = app.EndValueEditField.Value;
            v1_step = app.StepSizeEditField.Value;
            
            v2_start = 1000 * app.StartValueEditField_2.Value;
            v2_end = 1000 * app.EndValueEditField_2.Value;
            v2_step = 1000 * app.StepSizeEditField_2.Value;
            
            v3 = app.EditField.Value;
            v4 = app.BETEditField.Value;
            
                    para_alt(app, v2_start, v2_end, v2_step, v1_start , v1_end , v1_step,v3,v4)
            
                    
                   case 'BET'
                    
            v1_start = app.StartValueEditField.Value;
            v1_end = app.EndValueEditField.Value;
            v1_step = app.StepSizeEditField.Value;
            
            v2_start = 1000 * app.StartValueEditField_2.Value;
            v2_end = 1000 * app.EndValueEditField_2.Value;
            v2_step = 1000 * app.StepSizeEditField_2.Value;
            
            v3 = app.EditField.Value;
            v4 = app.BETEditField.Value;
            
                    parametric_BET(app, v2_start, v2_end, v2_step, v1_start , v1_end , v1_step,v3,v4) 
                    
            end
             
            
        end

        % Value changed function: Parameter2DropDown
        function Parameter2DropDownValueChanged(app, event)
         
             switch app.Parameter2DropDown.Value
            
                 case 'Altitude - km'
                app.CompressorRatioLabel.Text = 'Compressor Ratio';
                app.BETKLabel.Text = 'BET-K';

                    app.StartValueEditField_2.Value = 7;
                    app.EndValueEditField_2.Value = 15;
                    app.StepSizeEditField_2.Value = 1; 
                    
                    app.EditField.Value = 6;
                    app.BETEditField.Value = 1050;
                    
                    app.NTALTTab.Title = 'NT-ALT';
                    app.SFCNTTab.Title = 'SFC-NT';
                    app.SNTALTTab.Title = 'SNT-ALT';
                    app.SFCALTTab.Title = 'SFC-ALT';
                    app.MALTTab.Title = 'M-ALT';
                    
                  case 'Compressor Ratio'
               app.CompressorRatioLabel.Text = 'Altitude-km';
               app.BETKLabel.Text = 'BET-K';
            app.StartValueEditField_2.Value = 3;
            app.EndValueEditField_2.Value = 8;
            app.StepSizeEditField_2.Value = 1;
            
            app.EditField.Value = 12;
            app.BETEditField.Value = 1050;
            
                    app.NTALTTab.Title = 'NT-CPR';
                    app.SFCNTTab.Title = 'SFC-NT';
                    app.SNTALTTab.Title = 'SNT-CPR';
                    app.SFCALTTab.Title = 'SFC-CPR';
                    app.MALTTab.Title = 'M-CPR';
                    
                  case 'BET'
                app.CompressorRatioLabel.Text = 'Compressor Ratio';
                app.BETKLabel.Text = 'Altitude-km';
                
                    app.StartValueEditField_2.Value = 1050;
                    app.EndValueEditField_2.Value = 1200;
                    app.StepSizeEditField_2.Value = 50; 
                    
                    app.EditField.Value = 6;
                    app.BETEditField.Value = 12;
                    
                    app.NTALTTab.Title = 'NT-BET';
                    app.SFCNTTab.Title = 'SFC-NT';
                    app.SNTALTTab.Title = 'SNT-BET';
                    app.SFCALTTab.Title = 'SFC-BET';
                    app.MALTTab.Title = 'M-BET';
            
            end
        end

        % Button pushed function: CROPFIGUREButton
        function CROPFIGUREButtonPushed(app, event)
            sfc_min = app.MINEditField.Value;
            sfc_max = app.MAXEditField.Value;
            netthrust_min = app.MINEditField_2.Value;
            netthrust_max = app.MAXEditField_2.Value;
            
            app.UIAxes_2.XLim=([netthrust_min netthrust_max]);
            app.UIAxes_2.YLim=([sfc_min sfc_max]);
            
            
        end

        % Button pushed function: ExportDatatoExcelButton
        function ExportDatatoExcelButtonPushed(app, event)


            [file,path] = uiputfile('*.xlsx','Turbojet File');
            
            full_directory = fullfile(path,file);
            
            switch app.Parameter2DropDown.Value
                
                case 'Altitude - km'
                    
             switch app.GraphDropDown.Value
                
                    case '1'
                    writematrix(app.Data_1,full_directory) 
            
                    case '2'
                    writematrix(app.Data_2,full_directory) 
                    
                    case '3'
                    writematrix(app.Data_3,full_directory)
                    
                    case '4'
                    writematrix(app.Data_4,full_directory)
                    
                    case '5'
                    writematrix(app.Data_5,full_directory)
            
            end
            
                    case 'Compressor Ratio'
                     
           switch app.GraphDropDown.Value
                
                    case '1'
                    writematrix(app.Data_1,full_directory) 
            
                    case '2'
                    writematrix(app.Data_2,full_directory) 
                    
                    case '3'
                    writematrix(app.Data_3,full_directory)
                    
                    case '4'
                    writematrix(app.Data_4,full_directory)
                    
                    case '5'
                    writematrix(app.Data_5,full_directory)
            
           end
           
           case 'BET'
                    
             switch app.GraphDropDown.Value
                
                    case '1'
                    writematrix(app.Data_1,full_directory) 
            
                    case '2'
                    writematrix(app.Data_2,full_directory) 
                    
                    case '3'
                    writematrix(app.Data_3,full_directory)
                    
                    case '4'
                    writematrix(app.Data_4,full_directory)
                    
                    case '5'
                    writematrix(app.Data_5,full_directory)
            
            end
            
            end


        end

        % Button pushed function: DownloadFigureButton
        function DownloadFigureButtonPushed(app, event)
           
            [file,path] = uiputfile('*.png','Turbojet File');
            
            full_directory = fullfile(path,file);
            
            switch app.GraphDropDown.Value
                
                    case '1'
                    exportgraphics(app.UIAxes,full_directory,'Resolution',600) 
            
                    case '2'
                    exportgraphics(app.UIAxes_2,full_directory,'Resolution',600) 
                    
                    case '3'
                    exportgraphics(app.UIAxes_3,full_directory,'Resolution',600)
                    
                    case '4'
                    exportgraphics(app.UIAxes_4,full_directory,'Resolution',600)
                    
                    case '5'
                    exportgraphics(app.UIAxes_5,full_directory,'Resolution',600)
            
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'milad-fakurian-UiiHVEyxtyA-unsplash.jpg';
            app.VariableParametersPanel = uipanel(app.UIFigure);
            app.VariableParametersPanel.Title = 'Variable Parameters';
            app.StartValueEditFieldLabel = uilabel(app.VariableParametersPanel);
            app.StartValueEditFieldLabel.Text = 'Start Value';
            app.StartValueEditField = uieditfield(app.VariableParametersPanel, 'numeric');
            app.StartValueEditField.Limits = [0 1];
            app.EndValueEditFieldLabel = uilabel(app.VariableParametersPanel);
            app.EndValueEditFieldLabel.Text = 'End Value';
            app.EndValueEditField = uieditfield(app.VariableParametersPanel, 'numeric');
            app.EndValueEditField.Limits = [0 1];
            app.StepSizeEditFieldLabel = uilabel(app.VariableParametersPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.VariableParametersPanel, 'numeric');
            app.StepSizeEditField.Limits = [0 1];
            app.StepSizeEditField_2Label = uilabel(app.VariableParametersPanel);
            app.StepSizeEditField_2Label.Text = 'Step Size';
            app.StepSizeEditField_2 = uieditfield(app.VariableParametersPanel, 'numeric');
            app.EndValueEditField_2Label = uilabel(app.VariableParametersPanel);
            app.EndValueEditField_2Label.Text = 'End Value';
            app.EndValueEditField_2 = uieditfield(app.VariableParametersPanel, 'numeric');
            app.StartValueEditField_2Label = uilabel(app.VariableParametersPanel);
            app.StartValueEditField_2Label.Text = 'Start Value';
            app.StartValueEditField_2 = uieditfield(app.VariableParametersPanel, 'numeric');
            app.Parameter1DropDownLabel = uilabel(app.VariableParametersPanel);
            app.Parameter1DropDownLabel.Text = 'Parameter 1';
            app.Parameter1DropDown = uidropdown(app.VariableParametersPanel);
            app.Parameter1DropDown.Items = {'Mach No.'};
            app.Parameter1DropDown.Value = 'Mach No.';
            app.Parameter2DropDownLabel = uilabel(app.VariableParametersPanel);
            app.Parameter2DropDownLabel.Text = 'Parameter 2';
            app.Parameter2DropDown = uidropdown(app.VariableParametersPanel);
            app.Parameter2DropDown.Items = {'Compressor Ratio', 'Altitude - km', 'BET'};
            app.Parameter2DropDown.ValueChangedFcn = createCallbackFcn(app, @Parameter2DropDownValueChanged, true);
            app.Parameter2DropDown.Value = 'Altitude - km';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NTALTTab = uitab(app.TabGroup);
            app.NTALTTab.Title = 'NT - ALT';
            app.UIAxes = uiaxes(app.NTALTTab);
            title(app.UIAxes, 'sfc vs net thrust')
            xlabel(app.UIAxes, 'net thrust')
            ylabel(app.UIAxes, 'sfc')
            zlabel(app.UIAxes, 'Z')
            app.SFCNTTab = uitab(app.TabGroup);
            app.SFCNTTab.Title = 'SFC - NT';
            app.UIAxes_2 = uiaxes(app.SFCNTTab);
            title(app.UIAxes_2, 'sfc vs net thrust')
            xlabel(app.UIAxes_2, 'net thrust')
            ylabel(app.UIAxes_2, 'sfc')
            zlabel(app.UIAxes_2, 'Z')
            app.SNTALTTab = uitab(app.TabGroup);
            app.SNTALTTab.Title = 'SNT - ALT';
            app.UIAxes_3 = uiaxes(app.SNTALTTab);
            title(app.UIAxes_3, 'sfc vs net thrust')
            xlabel(app.UIAxes_3, 'net thrust')
            ylabel(app.UIAxes_3, 'sfc')
            zlabel(app.UIAxes_3, 'Z')
            app.SFCALTTab = uitab(app.TabGroup);
            app.SFCALTTab.Title = 'SFC - ALT';
            app.UIAxes_4 = uiaxes(app.SFCALTTab);
            title(app.UIAxes_4, 'sfc vs net thrust')
            xlabel(app.UIAxes_4, 'net thrust')
            ylabel(app.UIAxes_4, 'sfc')
            zlabel(app.UIAxes_4, 'Z')
            app.MALTTab = uitab(app.TabGroup);
            app.MALTTab.Title = 'M - ALT';
            app.UIAxes_5 = uiaxes(app.MALTTab);
            title(app.UIAxes_5, 'sfc vs net thrust')
            xlabel(app.UIAxes_5, 'net thrust')
            ylabel(app.UIAxes_5, 'sfc')
            zlabel(app.UIAxes_5, 'Z')
            app.ConstantParameterPanel = uipanel(app.UIFigure);
            app.ConstantParameterPanel.Title = 'Constant Parameter';
            app.CompressorRatioLabel = uilabel(app.ConstantParameterPanel);
            app.CompressorRatioLabel.Text = 'Compressor Ratio';
            app.EditField = uieditfield(app.ConstantParameterPanel, 'numeric');
            app.BETEditField = uieditfield(app.ConstantParameterPanel, 'numeric');
            app.Label = uilabel(app.ConstantParameterPanel);
            app.Label.Text = '';
            app.BETKLabel = uilabel(app.ConstantParameterPanel);
            app.BETKLabel.Text = 'BET-K';
            app.EngineSelectionPanel = uipanel(app.UIFigure);
            app.EngineSelectionPanel.Title = 'Engine Selection';
            app.MAXEditFieldLabel = uilabel(app.EngineSelectionPanel);
            app.MAXEditFieldLabel.Text = 'MAX';
            app.MAXEditField = uieditfield(app.EngineSelectionPanel, 'numeric');
            app.SFCLabel = uilabel(app.EngineSelectionPanel);
            app.SFCLabel.Text = 'SFC';
            app.MINEditFieldLabel = uilabel(app.EngineSelectionPanel);
            app.MINEditFieldLabel.Text = 'MIN';
            app.MINEditField = uieditfield(app.EngineSelectionPanel, 'numeric');
            app.NetThrustLabel = uilabel(app.EngineSelectionPanel);
            app.NetThrustLabel.Text = 'Net Thrust';
            app.MAXEditField_2Label = uilabel(app.EngineSelectionPanel);
            app.MAXEditField_2Label.Text = 'MAX';
            app.MAXEditField_2 = uieditfield(app.EngineSelectionPanel, 'numeric');
            app.MINEditField_2Label = uilabel(app.EngineSelectionPanel);
            app.MINEditField_2Label.Text = 'MIN';
            app.MINEditField_2 = uieditfield(app.EngineSelectionPanel, 'numeric');
            app.CROPFIGUREButton = uibutton(app.UIFigure, 'push');
            app.CROPFIGUREButton.ButtonPushedFcn = createCallbackFcn(app, @CROPFIGUREButtonPushed, true);
            app.CROPFIGUREButton.Text = 'CROP FIGURE';
            app.PARAMETRICANALYSISLabel = uilabel(app.UIFigure);
            app.PARAMETRICANALYSISLabel.Text = 'PARAMETRIC ANALYSIS';
            app.DownloadFigureButton = uibutton(app.UIFigure, 'push');
            app.DownloadFigureButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFigureButtonPushed, true);
            app.DownloadFigureButton.Text = 'Download Figure';
            app.ExportDatatoExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportDatatoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatoExcelButtonPushed, true);
            app.ExportDatatoExcelButton.Text = 'Export Data to Excel';
            app.GraphDropDownLabel = uilabel(app.UIFigure);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.UIFigure);
            app.GraphDropDown.Items = {'1', '2', '3', '4', '5'};
            app.GraphDropDown.Value = '1';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'skynews-typhoon-raf-file-image_5937093-transformed.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = para_test_1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end