classdef performance_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image2                         matlab.ui.control.Image
        PERFORMANCEANALYSISLabel       matlab.ui.control.Label
        GraphDropDown                  matlab.ui.control.DropDown
        GraphDropDownLabel             matlab.ui.control.Label
        ExportDatatoExcelButton        matlab.ui.control.Button
        DownloadFigureButton           matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        NTMaTab                        matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        SFCNTTab                       matlab.ui.container.Tab
        UIAxes_2                       matlab.ui.control.UIAxes
        SNTMaTab                       matlab.ui.container.Tab
        UIAxes_3                       matlab.ui.control.UIAxes
        SFCMaTab                       matlab.ui.container.Tab
        UIAxes_4                       matlab.ui.control.UIAxes
        T_EffMaTab                     matlab.ui.container.Tab
        UIAxes_5                       matlab.ui.control.UIAxes
        P_EffMaTab                     matlab.ui.container.Tab
        UIAxes_6                       matlab.ui.control.UIAxes
        RUNButton                      matlab.ui.control.Button
        ParameterSelectionPanel        matlab.ui.container.Panel
        KLabel                         matlab.ui.control.Label
        BETEditField                   matlab.ui.control.NumericEditField
        BETEditFieldLabel              matlab.ui.control.Label
        kmLabel                        matlab.ui.control.Label
        CompressorRatioEditFieldLabel_2  matlab.ui.control.Label
        CompressorRatioEditField_2     matlab.ui.control.NumericEditField
        CompressorRatioEditField       matlab.ui.control.NumericEditField
        CompressorRatioEditFieldLabel  matlab.ui.control.Label
        ParameterDropDown              matlab.ui.control.DropDown
        ParameterDropDownLabel         matlab.ui.control.Label
        StartValueEditField_2          matlab.ui.control.NumericEditField
        StartValueEditField_2Label     matlab.ui.control.Label
        EndValueEditField_2            matlab.ui.control.NumericEditField
        EndValueEditField_2Label       matlab.ui.control.Label
        StepSizeEditField_2            matlab.ui.control.NumericEditField
        StepSizeEditField_2Label       matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
        properties (Access = private)
        callingapp 
    end
    
    properties (Access = public)
        A1
        A2
        A3
        A4
        A5
        A6
        A7
        A8
        A9
        A10
        A11
        Data_1
        Data_2
        Data_3
        Data_4
        Data_5
            
    end


    methods (Access = public)
        
                                                   %% MACH PERFORMANCE
                                                   
function [net_thrust_array , sfc_array] = Per_Mach(app, mach_1 , mach_2 ,mach_step,  altitude, comp_ratio,bet)
num = int8((mach_2 - mach_1)*10)+1;
Temp = zeros(5,1);
Press = zeros(5,1);
s_vol = zeros(5,1);
s_entro = zeros(5,1);
net_thrust_array = zeros(1,num);
spec_net_thrust_array = zeros(1,num);
sfc_array = zeros(1,num);
Mach_Array = zeros(1,num);
air_mass_array = zeros(1,num);
Thermal_eff_array = zeros(1,num);
Prop_eff_array = zeros(1,num);
Overall_eff_array = zeros(1,num);

i = 1;
j = 1;

%[Po,To,Mo,cp_gas,tau_r,tau_d,tau_c,tau_b,tau_t,tau_n,pi_r,pi_d,pi_c,pi_b,pi_t,pi_n,Ttb_lim,Eff_c,Eff_b,Eff_t,Eff_n] = inputparameters ;

%[Po,To,Mo,mo,cp_gas, gamma_gas] = [100e+3 , 223 , 2 , 32 , 1000 , 1.4];


Eff_c = app.A1;
PR_burn = app.A5;
Eff_Noz = app.A2;
A_noz = app.A7;
Eff_isen_turb = app.A3;
Eff_mechn_turb = app.A4;
cp_gas = app.A6;  %J/Kg.Kelvin
Qhv = app.A10; %J/kg
gamma_gas = app.A11;
R = app.A8;

%%[R,gamma_air,gamma_gas,cp_air,cp_gas,hf]= standard_vals;
%%[ m2 , T2 , p2 , W] = Comp_Func ( Mo , To , Po ,pi_c , Eff_c , gamma_gas , cp_gas );


j = 1;

for mach = mach_1:mach_step:mach_2
    
BET = bet;
h = altitude;
%----------------------------------------------
if h <=11000
        Tamb = (15.04-(0.00649*h));
        Pamb = (101.29* ((Tamb+273.1)/288.08)^5.256);
    elseif h >11000 && h <25000
            Tamb = -56.46;
            Pamb = 22.65 * (exp(1.73-(.000157*h)));
        else 
            Tamb = -131.21 + (.00299 * h);
            Pamb = 2.488 * (((Tamb+273.1)/(216.6))^-11.388);
end
%---------------------------------------------
Velo_sound = sqrt(gamma_gas*R*(Tamb + 273));


Mo = mach;
PR_c = comp_ratio;

Mach_Array(i,j) = mach;
Velo_1 = Mo*Velo_sound
Dynamic_Temp = (Velo_1^2)/(2*cp_gas);
Pt = Pamb*1000*(1 + Dynamic_Temp/(Tamb + 273))^(gamma_gas/(gamma_gas-1)); %Pascals
Tt = Tamb + 273.16 + Dynamic_Temp; 
%Kelvin

Po = Pt ;%Pascals
To = Tt ;%Kelvin

Temp(1,1) = To;
Press(1,1) = Po;

To
Po

%Compressor Function Call
Temp(2,1) = To;
Press(2,1) = Po;
[ T2 , p2  W_c ] = Comp_Func (app,  To , Po , PR_c , Eff_c , gamma_gas , cp_gas);
Temp(3,1) = T2;
Press(3,1) = p2;

T2 
p2  
W_c

%Burner Function Call
[ T3 ,p3 ,AF ] = burner_func (app,  T2 , p2 ,  cp_gas ,PR_burn,BET,Qhv );
Temp(4,1) = T3;
Press(4,1) = p3;

T3 
p3 
AF
%Turbine Function Call
W_t = W_c;
[ T4 , p4 ] = Turb_Func(app, T3 , p3 , Eff_isen_turb , Eff_mechn_turb , W_t , gamma_gas , cp_gas);
Temp(5,1) = T4;
Press(5,1) = p4;

T4 
p4
%Nozzle Function Call
[ m5 , T5 , p5 , Velo_5 ] = Nozzle_Func(app, T4 , p4 , Eff_Noz , gamma_gas , A_noz,R);
Temp(6,1) = T5;
Press(6,1) = p5;
m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;

m5 
T5 
p5 
Velo_5
%Thrust Function Call
[net_thrust , net_thrust_spec] = thrust_func(app, Velo_1,Velo_5, m5,Po,p5,A_noz,m_inlet);
net_thrust_array(i,j) = net_thrust;
spec_net_thrust_array(i,j) = net_thrust_spec;

net_thrust 
net_thrust_spec
%SFC Calculation
m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;
air_mass_array(i,j) = m_inlet;
specific_fuel = m_fuel/net_thrust;
sfc_array(i,j) = specific_fuel;

Kinetic_change = (0.5)*((m5*Velo_5^2)-(m_inlet*Velo_1^2));
Thermal_eff_array(i,j) = Kinetic_change/(m_fuel*Qhv);
Prop_eff_array(i,j) = (net_thrust*Velo_1)/Kinetic_change;
Overall_eff_array(i,j) = Thermal_eff_array(i,j) * Prop_eff_array(i,j);


j = j + 1;

end


%PLOTS


plot(app.UIAxes,  Mach_Array(i,:), net_thrust_array(i,:) ,'-o')
hold(app.UIAxes, 'on')
%title(app.UIAxes, 'Net Thrust vs Mach')
xlabel(app.UIAxes, 'Mach')
ylabel(app.UIAxes, 'Net Thrust / N ')


plot(app.UIAxes_2, net_thrust_array(i,:), sfc_array(i,:) ,'-o')
hold(app.UIAxes_2, 'on')
%title(app.UIAxes_2, 'Specific Fuel Consumption vs Net Thrust')
xlabel(app.UIAxes_2, 'Net Thrust / N ')
ylabel(app.UIAxes_2, 'Specific Fuel Comsumption (SFC) / (kg/s.N) ')


plot(app.UIAxes_3, Mach_Array(i,:) , spec_net_thrust_array(i,:) ,'-o')
hold(app.UIAxes_3, 'on')
%title(app.UIAxes_3, 'Specific Net Thrust vs Mach')
xlabel(app.UIAxes_3, 'Mach')
ylabel(app.UIAxes_3, 'Spec Net Thrust / (N.s/kg) ')


plot(app.UIAxes_4, Mach_Array(i,:) , sfc_array(i,:) ,'-o')
hold(app.UIAxes_4, 'on')
%title(app.UIAxes_4, 'Specific Fuel Comsumption vs Mach')
xlabel(app.UIAxes_4, 'Mach')
ylabel(app.UIAxes_4, 'Specific Fuel Comsumption (SFC) / (kg/s.N) ')


plot(app.UIAxes_5, Mach_Array(i,:) , Thermal_eff_array(i,:) ,'-o')
hold(app.UIAxes_5, 'on')
%title(app.UIAxes_5, 'Thermal Effeciency vs Mach')
xlabel(app.UIAxes_5, 'Mach')
ylabel(app.UIAxes_5, 'Thermal Effeciency')

plot(app.UIAxes_6, Mach_Array(i,:) , Prop_eff_array(i,:) ,'-o')
hold(app.UIAxes_6, 'on')
%title(app.UIAxes_6, 'Propulsive Effeciency vs Mach')
xlabel(app.UIAxes_6, 'Mach')
ylabel(app.UIAxes_6, 'Propulsive Effeciency')


        end
%---------------------------------------------------------------------------------
        
                                        %% ALT PERFORMANCE
                                        
function [net_thrust_array , sfc_array] = Per_Alt(app, alt_1 , alt_2 ,alt_step, mach, comp_ratio,bet)
num = (alt_2-alt_1)/1000 + 1;
Temp = zeros(5,1);
Press = zeros(5,1);
net_thrust_array = zeros(1,num);
spec_net_thrust_array = zeros(1,num);
sfc_array = zeros(1,num);
Alt_array = zeros(1,num);
air_mass_array = zeros(1,num);
Thermal_eff_array = zeros(1,num);
Prop_eff_array = zeros(1,num);
Overall_eff_array = zeros(1,num);


%%[R,gamma_air,gamma_gas,cp_air,cp_gas,hf]= standard_vals;
%%[ m2 , T2 , p2 , W] = Comp_Func ( Mo , To , Po ,pi_c , Eff_c , gamma_gas , cp_gas );


Eff_c = app.A1;
PR_burn = app.A5;
Eff_Noz = app.A2;
A_noz = app.A7;
Eff_isen_turb = app.A3;
Eff_mechn_turb = app.A4;
cp_gas = app.A6;  %J/Kg.Kelvin
Qhv = app.A10; %J/kg
gamma_gas = app.A11;
R = app.A8;

i = 1;
j = 1;

%[Po,To,Mo,cp_gas,tau_r,tau_d,tau_c,tau_b,tau_t,tau_n,pi_r,pi_d,pi_c,pi_b,pi_t,pi_n,Ttb_lim,Eff_c,Eff_b,Eff_t,Eff_n] = inputparameters ;

%[Po,To,Mo,mo,cp_gas, gamma_gas] = [100e+3 , 223 , 2 , 32 , 1000 , 1.4];


BET = bet;
Mo = mach;
PR_c = comp_ratio;


for altitude = alt_1:alt_step:alt_2

    h = altitude;
    %----------------------------------------------
if h <=11000
        Tamb = (15.04-(0.00649*h));
        Pamb = (101.29* ((Tamb+273.1)/288.08)^5.256);
    elseif h >11000 && h <25000
            Tamb = -56.46;
            Pamb = 22.65 * (exp(1.73-(.000157*h)));
        else 
            Tamb = -131.21 + (.00299 * h);
            Pamb = 2.488 * (((Tamb+273.1)/(216.6))^-11.388);
end
%---------------------------------------------


Velo_sound = sqrt(gamma_gas*R*(Tamb + 273));
Velo_1 = Mo*Velo_sound;
Dynamic_Temp = (Velo_1^2)/(2*cp_gas);
Pt = Pamb*1000*(1 + Dynamic_Temp/(Tamb + 273))^(gamma_gas/(gamma_gas-1)); %Pascals
Tt = Tamb + 273.16 + Dynamic_Temp; 
%Kelvin

Po = Pt ;%Pascals
To = Tt ;%Kelvin


Temp(1,1) = To;
Press(1,1) = Po;


Alt_array(i,j) = altitude;


%Compressor Function Call
Temp(2,1) = To;
Press(2,1) = Po;
[ T2 , p2  W_c ] = Comp_Func (app,  To , Po , PR_c , Eff_c , gamma_gas , cp_gas);
Temp(3,1) = T2;
Press(3,1) = p2;

%Burner Function Call
[ T3 ,p3 ,AF ] = burner_func (app,  T2 , p2 ,  cp_gas ,PR_burn,BET,Qhv );

Temp(4,1) = T3;
Press(4,1) = p3;

%Turbine Function Call

W_t = W_c;

[ T4 , p4 ] = Turb_Func(app, T3 , p3 , Eff_isen_turb , Eff_mechn_turb , W_t , gamma_gas , cp_gas);

Temp(5,1) = T4;
Press(5,1) = p4;

%Nozzle Function Call

[ m5 , T5 , p5 , Velo_5 ] = Nozzle_Func(app, T4 , p4 , Eff_Noz , gamma_gas , A_noz,R);

Temp(6,1) = T5;
Press(6,1) = p5;

m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;

%Thrust Function Call
[net_thrust , net_thrust_spec] = thrust_func(app, Velo_1,Velo_5, m5,Po,p5,A_noz,m_inlet);

net_thrust_array(i,j) = net_thrust;
spec_net_thrust_array(i,j) = net_thrust_spec;

%SFC Calculation
m_fuel = m5 /(1+AF);
m_inlet = m5-m_fuel;
air_mass_array(i,j) = m_inlet;
specific_fuel = m_fuel/net_thrust;
sfc_array(i,j) = specific_fuel;

Kinetic_change = (0.5)*((m5*Velo_5^2)-(m_inlet*Velo_1^2));
Thermal_eff_array(i,j) = Kinetic_change/(m_fuel*Qhv);
Prop_eff_array(i,j) = (net_thrust*Velo_1)/Kinetic_change;
Overall_eff_array(i,j) = Thermal_eff_array(i,j) * Prop_eff_array(i,j);

j = j + 1;

end


%PLOTS


plot(app.UIAxes, Alt_array(i,:), net_thrust_array(i,:) ,'-o')
hold(app.UIAxes, 'on')
%title(app.UIAxes, 'Net Thrust vs Altitude')
xlabel(app.UIAxes, 'Altitude / m ')
ylabel(app.UIAxes, 'Net Thrust / N ')


plot(app.UIAxes_2, net_thrust_array(i,:), sfc_array(i,:) ,'-o')
hold(app.UIAxes_2, 'on')
%title(app.UIAxes_2, 'Specific Fuel Consumption vs Net Thrust')
xlabel(app.UIAxes_2, 'Net Thrust / N ')
ylabel(app.UIAxes_2, 'Specific Fuel Comsumption (SFC) / kg/s/N ')


plot(app.UIAxes_3, Alt_array(i,:) , spec_net_thrust_array(i,:) ,'-o')
hold(app.UIAxes_3, 'on')
%title(app.UIAxes_3, 'Specific Net Thrust vs Altitude')
xlabel(app.UIAxes_3, 'Altitude / m  ')
ylabel(app.UIAxes_3, 'Spec Net Thrust / N.s/kg ')


plot(app.UIAxes_4, Alt_array(i,:) , sfc_array(i,:) ,'-o')
hold(app.UIAxes_4, 'on')
%title(app.UIAxes_4, 'Specific Fuel Comsumption vs Altitude')
xlabel(app.UIAxes_4, 'Altitude / m  ')
ylabel(app.UIAxes_4, 'Specific Fuel Comsumption (SFC) / kg/s/N ')


plot(app.UIAxes_5, Alt_array(i,:) , Thermal_eff_array(i,:) ,'-o')
hold(app.UIAxes_5, 'on')
%title(app.UIAxes_5, 'Thermal Effeciency vs Altitude')
xlabel(app.UIAxes_5, 'Altitude / m ')
ylabel(app.UIAxes_5, 'Thermal Effeciency ')


plot(app.UIAxes_6, Alt_array(i,:) , Prop_eff_array(i,:) ,'-o')
hold(app.UIAxes_6, 'on')
%title(app.UIAxes_6, 'Propulsive Effeciency vs Altitude')
xlabel(app.UIAxes_6, 'Altitude')
ylabel(app.UIAxes_6, 'Propulsive Effeciency')

end


%------------------------------------------------------------------------------------------------------

%% Fucntions Attached

    
function [T2 , P2, W] = Comp_Func (app,   T1 , P1 ,PR , Eff , gamma , Cpa )
    % Calculates the massflow , temperature and pressure of a compressor with a
    % given massflow , pressure , pressure ratio , efficiency and using the gamma
    % and CP of air.
    % Calculate Pressure
    P2=PR*P1;
    % Calculate Temperature using Isentropic efficiency
    T2 = T1*(1/Eff)*((P2/P1)^(( gamma -1)/ gamma ) -1) + T1;
    % Calculate work done by the compressor
    W=Cpa *(T2 -T1 );
end
    function [ T2 ,P2 , AF ] = burner_func (app,  T1 , P1 , Cpg, PR_burn, BET, Qhv )

T2=BET;
P2=PR_burn*P1;
%AF
delta_T = T2-T1;
AF = (Qhv/(Cpg * delta_T))-1;
    end
    
function [ m2 , T2 , P2 , Velo_2 ] = Nozzle_Func(app,  T1, P1, Eff_noz, gamma_gas, A_noz,R )
% Calculates the massflow , temperature and pressure of an exhaust with a given
%  pressure , pressure ratio , efficiency and using the gamma of gas,
%  Area,entropy at inlet

%Finding Pc
exponent = (gamma_gas)/(gamma_gas-1);
ratio = (1-(1/Eff_noz)*((gamma_gas-1)/(gamma_gas+1)))^exponent ;
Pc = P1*ratio;
%Calculate T2
T2 = T1*(2*(1/(gamma_gas + 1)));

%Calculating exit pressure
P2= P1*(ratio);

%calcuate density
row = Pc/(R*T2);
%calcuate velocity
Velo_2 = (gamma_gas*R*T2)^0.5;
%Calculating mass flow rate
m2 = A_noz * row * Velo_2;

end

function [ T2 , P2 ] = Turb_Func(app,  T1 , P1 , Eff_isen , Eff_mechn , W , gamma_gas , Cpg )
% Calculates the massflow , temperature and pressure of a turbine with a given
% massflow , pressure , Mechanical and isentropic efficiencies , the work done ,
% the cp of gass and using the gamma of gas
% Calculate temperature difference due to work done
Delta_T = W/(Cpg*Eff_mechn);
%Calculating T2
T2 = T1 - Delta_T;
%Calculate P2 with isentropic effeciency
P2 = (1/Eff_isen)*P1*((T2/T1)^(gamma_gas/(gamma_gas-1)) - 1 ) + P1;
end

function [thrust_net , thrust_s] = thrust_func(app, Velo_1,Velo_2, m2,Po,p2,A_noz,m_inlet)

%Calculating Thrust

%gross thrust

pressure_thrust = (A_noz)*(p2-Po);

thrust_g = m2*Velo_2;

%Ram Drag

ram_drag = m_inlet*Velo_1;

%Net Thrust

thrust_net = thrust_g + pressure_thrust - ram_drag ;

%Spec Net Thrust

thrust_s = thrust_net/m_inlet;


end


function holding_data(app , cpr_eff,Nozz_eff, Tur_eff, Tur_mech_eff, Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma  )
       
       app.A1 = cpr_eff;
       app.A2=Nozz_eff;
       app.A3=Tur_eff;
       app.A4=Tur_mech_eff;
       app.A5=Burn_eff;
       app.A6=Cp;
       app.A7=nozz_area;
       app.A8=Gas_Constant;
       app.A10=Qhv;
       app.A11=Gamma;
                         
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MAINPAGE, cpr_eff, Nozz_eff, Tur_eff, Tur_mech_eff, Burn_eff, Cp, nozz_area, Gas_Constant, Qhv, Gamma)
            app.kmLabel.Text = 'km';
            app.KLabel.Text = 'K';
            
            app.StartValueEditField_2.Value = 0.3;
            app.EndValueEditField_2.Value = 0.8;
            app.StepSizeEditField_2.Value = 0.1;
            
            app.CompressorRatioEditField.Value = 8;
            app.CompressorRatioEditField_2.Value = 12;
            app.BETEditField.Value = 1050;
            
            app.callingapp = MAINPAGE;
            
            holding_data(app , cpr_eff,Nozz_eff, Tur_eff, Tur_mech_eff, Burn_eff,Cp,nozz_area,Gas_Constant,Qhv,Gamma )
            
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            app.Data_1 = [];
            app.Data_2 = [];
            app.Data_3 = [];
            app.Data_4 = [];
            app.Data_5 = [];


                cla(app.UIAxes, "reset");
                cla(app.UIAxes_2,"reset");
                cla(app.UIAxes_3,"reset");
                cla(app.UIAxes_4,"reset");
                cla(app.UIAxes_5,"reset");
                cla(app.UIAxes_6,"reset");


            switch app.ParameterDropDown.Value
                
                case 'Mach Number'
                    
            v1_start = app.StartValueEditField_2.Value;
            v1_end = app.EndValueEditField_2.Value;
            v1_step = app.StepSizeEditField_2.Value;
            
            comp_ratio = app.CompressorRatioEditField.Value;
            
            v2_value = 1000 * app.CompressorRatioEditField_2.Value;
            
            bet = app.BETEditField.Value;
                    
                  Per_Mach(app, v1_start, v1_end, v1_step, v2_value , comp_ratio,bet)  
            
                case 'Altitude / km'
                    
            v1_start = 1000* app.StartValueEditField_2.Value;
            v1_end = 1000* app.EndValueEditField_2.Value;
            v1_step = 1000* app.StepSizeEditField_2.Value;
            
            comp_ratio = app.CompressorRatioEditField.Value;
            
            v2_value = app.CompressorRatioEditField_2.Value;
            
            bet = app.BETEditField.Value;
                    
                  Per_Alt(app, v1_start, v1_end, v1_step, v2_value , comp_ratio,bet)  
            
            end
             
            
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            switch app.ParameterDropDown.Value
            
                case 'Mach Number'
            app.kmLabel.Text = 'km';         
                    
            app.CompressorRatioEditFieldLabel_2.Text = 'Altitude';
                
            app.StartValueEditField_2.Value = 0.3;
            app.EndValueEditField_2.Value = 0.8;
            app.StepSizeEditField_2.Value = 0.1;
            
            app.CompressorRatioEditField_2.Value = 12;
            
            app.NTMaTab.Title = 'NT-Ma';
            app.SFCNTTab.Title='SFC-NT';
            app.SNTMaTab.Title='SNT-Ma';
            app.SFCMaTab.Title='SFC-Ma';
            app.T_EffMaTab.Title='T_Eff-Ma';
            app.P_EffMaTab.Title='P_Eff-Ma';
                
                
                case 'Altitude / km'
            app.CompressorRatioEditFieldLabel_2.Text = 'Mach Number';
                    
            app.kmLabel.Text = '';        
                    
            app.StartValueEditField_2.Value = 7;
            app.EndValueEditField_2.Value = 15;
            app.StepSizeEditField_2.Value = 1;
            
            app.CompressorRatioEditField_2.Value = .8;
            
            app.NTMaTab.Title = 'NT-Alt';
            app.SFCNTTab.Title='SFC-NT';
            app.SNTMaTab.Title='SNT-Alt';
            app.SFCMaTab.Title='SFC-Alt';
            app.T_EffMaTab.Title='T_Eff-Alt';
            app.P_EffMaTab.Title='P_Eff-Alt';
            
            end
            
        end

        % Button pushed function: DownloadFigureButton
        function DownloadFigureButtonPushed(app, event)
             [file,path] = uiputfile('*.png','Turbojet File');
            
            full_directory = fullfile(path,file);


            switch app.GraphDropDown.Value
                
                    case '1'
                    exportgraphics(app.UIAxes,full_directory,'Resolution',600) 
            
                    case '2'
                    exportgraphics(app.UIAxes_2,full_directory,'Resolution',600) 
                    
                    case '3'
                    exportgraphics(app.UIAxes_3,full_directory,'Resolution',600)
                    
                    case '4'
                    exportgraphics(app.UIAxes_4,full_directory,'Resolution',600)
                    
                    case '5'
                    exportgraphics(app.UIAxes_5,full_directory,'Resolution',600)
                    
                    case '6'
                    exportgraphics(app.UIAxes_6,full_directory,'Resolution',600)
            
            end
        end

        % Button pushed function: ExportDatatoExcelButton
        function ExportDatatoExcelButtonPushed(app, event)
            [file,path] = uiputfile('*.xlsx','Turbojet File');
            
            full_directory = fullfile(path,file);
            
            switch app.Parameter2DropDown.Value
                
                    case 'Altitude'
                    
             switch app.GraphDropDown.Value
                
                    case '1'
                    writematrix(app.Data_1,full_directory) 
            
                    case '2'
                    writematrix(app.Data_2,full_directory) 
                    
                    case '3'
                    writematrix(app.Data_3,full_directory)
                    
                    case '4'
                    writematrix(app.Data_4,full_directory)
                    
                    case '5'
                    writematrix(app.Data_5,full_directory)
            
            end
            
                    case 'Compressor Ratio'
                     
           switch app.GraphDropDown.Value
                
                    case '1'
                    writematrix(app.Data_1,full_directory) 
            
                    case '2'
                    writematrix(app.Data_2,full_directory) 
                    
                    case '3'
                    writematrix(app.Data_3,full_directory)
                    
                    case '4'
                    writematrix(app.Data_4,full_directory)
                    
                    case '5'
                    writematrix(app.Data_5,full_directory)
            
            end
            
            end
            
            app.Data_1 = [];
            app.Data_2 = [];
            app.Data_3 = [];
            app.Data_4 = [];
            app.Data_5 = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'milad-fakurian-UiiHVEyxtyA-unsplash.jpg';
            app.ParameterSelectionPanel = uipanel(app.UIFigure);
            app.ParameterSelectionPanel.Title = 'Parameter Selection';
            app.StepSizeEditField_2Label = uilabel(app.ParameterSelectionPanel);
            app.StepSizeEditField_2Label.Text = 'Step Size';
            app.StepSizeEditField_2 = uieditfield(app.ParameterSelectionPanel, 'numeric');
            app.EndValueEditField_2Label = uilabel(app.ParameterSelectionPanel);
            app.EndValueEditField_2Label.Text = 'End Value';
            app.EndValueEditField_2 = uieditfield(app.ParameterSelectionPanel, 'numeric');
            app.StartValueEditField_2Label = uilabel(app.ParameterSelectionPanel);
            app.StartValueEditField_2Label.Text = 'Start Value';
            app.StartValueEditField_2 = uieditfield(app.ParameterSelectionPanel, 'numeric');
            app.ParameterDropDownLabel = uilabel(app.ParameterSelectionPanel);
            app.ParameterDropDownLabel.Text = 'Parameter ';
            app.ParameterDropDown = uidropdown(app.ParameterSelectionPanel);
            app.ParameterDropDown.Items = {'Mach Number', 'Altitude / km'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Value = 'Mach Number';
            app.CompressorRatioEditFieldLabel = uilabel(app.ParameterSelectionPanel);
            app.CompressorRatioEditFieldLabel.Text = 'Compressor Ratio';
            app.CompressorRatioEditField = uieditfield(app.ParameterSelectionPanel, 'numeric');
            app.CompressorRatioEditField_2 = uieditfield(app.ParameterSelectionPanel, 'numeric');
            app.CompressorRatioEditFieldLabel_2 = uilabel(app.ParameterSelectionPanel);
            app.CompressorRatioEditFieldLabel_2.Text = 'Altitude';
            app.kmLabel = uilabel(app.ParameterSelectionPanel);
            app.kmLabel.Text = 'km';
            app.BETEditFieldLabel = uilabel(app.ParameterSelectionPanel);
            app.BETEditFieldLabel.Text = 'BET';
            app.BETEditField = uieditfield(app.ParameterSelectionPanel, 'numeric');
            app.KLabel = uilabel(app.ParameterSelectionPanel);
            app.KLabel.Text = 'K';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NTMaTab = uitab(app.TabGroup);
            app.NTMaTab.Title = 'NT-Ma';
            app.UIAxes = uiaxes(app.NTMaTab);
            title(app.UIAxes, 'sfc vs net thrust')
            xlabel(app.UIAxes, 'net thrust')
            ylabel(app.UIAxes, 'sfc')
            zlabel(app.UIAxes, 'Z')
            app.SFCNTTab = uitab(app.TabGroup);
            app.SFCNTTab.Title = 'SFC-NT';
            app.UIAxes_2 = uiaxes(app.SFCNTTab);
            title(app.UIAxes_2, 'sfc vs net thrust')
            xlabel(app.UIAxes_2, 'net thrust')
            ylabel(app.UIAxes_2, 'sfc')
            zlabel(app.UIAxes_2, 'Z')
            app.SNTMaTab = uitab(app.TabGroup);
            app.SNTMaTab.Title = 'SNT-Ma';
            app.UIAxes_3 = uiaxes(app.SNTMaTab);
            title(app.UIAxes_3, 'sfc vs net thrust')
            xlabel(app.UIAxes_3, 'net thrust')
            ylabel(app.UIAxes_3, 'sfc')
            zlabel(app.UIAxes_3, 'Z')
            app.SFCMaTab = uitab(app.TabGroup);
            app.SFCMaTab.Title = 'SFC-Ma';
            app.UIAxes_4 = uiaxes(app.SFCMaTab);
            title(app.UIAxes_4, 'sfc vs net thrust')
            xlabel(app.UIAxes_4, 'net thrust')
            ylabel(app.UIAxes_4, 'sfc')
            zlabel(app.UIAxes_4, 'Z')
            app.T_EffMaTab = uitab(app.TabGroup);
            app.T_EffMaTab.Title = 'T_Eff-Ma';
            app.UIAxes_5 = uiaxes(app.T_EffMaTab);
            title(app.UIAxes_5, 'sfc vs net thrust')
            xlabel(app.UIAxes_5, 'net thrust')
            ylabel(app.UIAxes_5, 'sfc')
            zlabel(app.UIAxes_5, 'Z')
            app.P_EffMaTab = uitab(app.TabGroup);
            app.P_EffMaTab.Title = 'P_Eff-Ma';
            app.UIAxes_6 = uiaxes(app.P_EffMaTab);
            title(app.UIAxes_6, 'sfc vs net thrust')
            xlabel(app.UIAxes_6, 'net thrust')
            ylabel(app.UIAxes_6, 'sfc')
            zlabel(app.UIAxes_6, 'Z')
            app.DownloadFigureButton = uibutton(app.UIFigure, 'push');
            app.DownloadFigureButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadFigureButtonPushed, true);
            app.DownloadFigureButton.Text = 'Download Figure';
            app.ExportDatatoExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportDatatoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatatoExcelButtonPushed, true);
            app.ExportDatatoExcelButton.Text = 'Export Data to Excel';
            app.GraphDropDownLabel = uilabel(app.UIFigure);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.UIFigure);
            app.GraphDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.GraphDropDown.Value = '1';
            app.PERFORMANCEANALYSISLabel = uilabel(app.UIFigure);
            app.PERFORMANCEANALYSISLabel.Text = 'PERFORMANCE ANALYSIS';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'skynews-typhoon-raf-file-image_5937093-transformed.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = performance_test(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end